classdef app_test_1210 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Scheduler                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        LinksLabel                      matlab.ui.control.Label
        IntroductionLabel               matlab.ui.control.Label
        Image_softwareArchi             matlab.ui.control.Image
        ConstructionScheduleOptimisationLabel  matlab.ui.control.Label
        FeaturesLabel                   matlab.ui.control.Label
        StepsLabel_3                    matlab.ui.control.Label
        LinktodetaileddocumentationxxxLabel_3  matlab.ui.control.Label
        StartText2                      matlab.ui.control.Label
        TabData1                        matlab.ui.container.Tab
        ReadlistsofcomponentanddatabaseLabel  matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        StructuralcompoTab              matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        IfapplicableChangeunitTab       matlab.ui.container.Tab
        clickonlyonceLabel              matlab.ui.control.Label
        Converttheunitsofthesevalues_structListBox  matlab.ui.control.ListBox
        ConverttheunitsofthesevaluesfrommtommListBoxLabel  matlab.ui.control.Label
        TochangeunitofsomecolumnsegfrommtommLabel_2  matlab.ui.control.Label
        Needtoconvertfrommtomm_structSwitch  matlab.ui.control.Switch
        NeedtoconvertfrommtommSwitchLabel  matlab.ui.control.Label
        Updatecompolist_struct_changeUnitButton  matlab.ui.control.Button
        KeepdiscardcompoTab             matlab.ui.container.Tab
        Tokeepdiscard_Updatecompolist_structButton  matlab.ui.control.Button
        Tokeepdiscard_Applyfilteringrule2_structCheckBox  matlab.ui.control.CheckBox
        Tokeepdiscard_Applyfilteringrule1_structCheckBox  matlab.ui.control.CheckBox
        TokeepdiscardcompoLabel_3       matlab.ui.control.Label
        Tokeepdiscard_ignorecase_struct_rule2ListBox  matlab.ui.control.ListBox
        ignorecaseListBox_2Label        matlab.ui.control.Label
        Tokeepdiscard_ignorecase_struct_rule1ListBox  matlab.ui.control.ListBox
        ignorecaseListBoxLabel          matlab.ui.control.Label
        Tokeepdiscard_intheir_struct_rule2ListBox  matlab.ui.control.ListBox
        intheirListBox_5Label           matlab.ui.control.Label
        Tokeepdiscard_struct_rule2ListBox  matlab.ui.control.ListBox
        ToLabel_2                       matlab.ui.control.Label
        Tokeepdiscard_thosecompowith_struct_rule2TextArea  matlab.ui.control.TextArea
        thosecompowiththefollowingwordsTextArea_2Label  matlab.ui.control.Label
        Tokeepdiscard_intheir_struct_rule1ListBox  matlab.ui.control.ListBox
        intheirListBox_4Label           matlab.ui.control.Label
        Tokeepdiscard_struct_rule1ListBox  matlab.ui.control.ListBox
        ToLabel                         matlab.ui.control.Label
        Tokeepdiscard_thosecompowith_struct_rule1TextArea  matlab.ui.control.TextArea
        thosecompowiththefollowingwordsTextAreaLabel  matlab.ui.control.Label
        UpdateclassnamesTab             matlab.ui.container.Tab
        ToUpdateClass_ignorecase_structListBox  matlab.ui.control.ListBox
        ignorecaseListBox_5Label        matlab.ui.control.Label
        ToUpdateClass_intheir_structListBox  matlab.ui.control.ListBox
        intheirListBox_8Label           matlab.ui.control.Label
        CurrentclassnamesPanel          matlab.ui.container.Panel
        Currentclassnames_structTextArea  matlab.ui.control.TextArea
        ToUpdateClass_Updatecompolist_structButton  matlab.ui.control.Button
        ToUpdateClass_writerecognisable_structTextArea  matlab.ui.control.TextArea
        writerecognisableclassnameinsequenceLabel  matlab.ui.control.Label
        ToUpdateClass_Forcompowith_structTextArea  matlab.ui.control.TextArea
        ForcompowiththesewordsTextAreaLabel  matlab.ui.control.Label
        UpdateclassnamesLabel           matlab.ui.control.Label
        UpdatematerialnamesTab          matlab.ui.container.Tab
        CurrentmaterialnamesPanel       matlab.ui.container.Panel
        Currentmaterialnames_structTextArea  matlab.ui.control.TextArea
        ToUpdateMaterial_writetheirmaterial_structTextArea  matlab.ui.control.TextArea
        writerecognisablematerialnameinsequenceLabel  matlab.ui.control.Label
        ToUpdateMaterial_Foreachof_structTextArea  matlab.ui.control.TextArea
        ForeachoftheseclassesTextAreaLabel  matlab.ui.control.Label
        ToUpdateMaterial_Updatecompolist_structButton  matlab.ui.control.Button
        UpdatematerialnamesLabel        matlab.ui.control.Label
        FurtherprocessthestructuralcompolistLabel  matlab.ui.control.Label
        Directory_structCompoEditField  matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        UITable_structCompo             matlab.ui.control.Table
        ReadstructuralcomponentlistLabel  matlab.ui.control.Label
        ReadfileButton_structCompo      matlab.ui.control.Button
        MEPcompoTab                     matlab.ui.container.Tab
        TabGroup5_2                     matlab.ui.container.TabGroup
        IfapplicableChangeunitTab_2     matlab.ui.container.Tab
        clickonlyonceLabel_2            matlab.ui.control.Label
        Converttheunitsofthesevalues_mepListBox  matlab.ui.control.ListBox
        ConverttheunitsofthesevaluesfrommtommListBox_2Label  matlab.ui.control.Label
        TochangeunitofsomecolumnsegfrommtommLabel_3  matlab.ui.control.Label
        Needtoconvertfrommtomm_mepSwitch  matlab.ui.control.Switch
        NeedtoconvertfrommtommSwitch_2Label  matlab.ui.control.Label
        Updatecompolist_mep_changeUnitButton  matlab.ui.control.Button
        KeepdiscardcompoTab_2           matlab.ui.container.Tab
        Tokeepdiscard_Updatecompolist_mepButton  matlab.ui.control.Button
        Tokeepdiscard_Applyfilteringrule2_mepCheckBox  matlab.ui.control.CheckBox
        Tokeepdiscard_Applyfilteringrule1_mepCheckBox  matlab.ui.control.CheckBox
        Tokeepdiscard_Keepdiscardcompo_mepLabel  matlab.ui.control.Label
        Tokeepdiscard_ignorecase_mep_rule2ListBox  matlab.ui.control.ListBox
        ignorecaseListBox_4Label        matlab.ui.control.Label
        Tokeepdiscard_ignorecase_mep_rule1ListBox  matlab.ui.control.ListBox
        ignorecaseListBox_3Label        matlab.ui.control.Label
        Tokeepdiscard_intheir_mep_rule2ListBox  matlab.ui.control.ListBox
        intheirListBox_7Label           matlab.ui.control.Label
        Tokeepdiscard_mep_rule2ListBox  matlab.ui.control.ListBox
        ToListBox_4Label                matlab.ui.control.Label
        Tokeepdiscard_thosecompowith_mep_rule2TextArea  matlab.ui.control.TextArea
        thosecompowiththefollowingwordsTextArea_4Label  matlab.ui.control.Label
        Tokeepdiscard_intheir_mep_rule1ListBox  matlab.ui.control.ListBox
        intheirListBox_6Label           matlab.ui.control.Label
        Tokeepdiscard_mep_rule1ListBox  matlab.ui.control.ListBox
        ToListBox_3Label                matlab.ui.control.Label
        Tokeepdiscard_thosecompowith_mep_rule1TextArea  matlab.ui.control.TextArea
        thosecompowiththefollowingwordsTextArea_3Label  matlab.ui.control.Label
        UpdateclassnamesTab_2           matlab.ui.container.Tab
        ToUpdateClass_writerecognisable_mepTextArea  matlab.ui.control.TextArea
        writerecognisableclassnameinsequenceLabel_2  matlab.ui.control.Label
        ToUpdateClass_Forcompowith_mepTextArea  matlab.ui.control.TextArea
        ForcompowiththesewordsTextAreaLabel_2  matlab.ui.control.Label
        ToUpdateClass_ignorecase_mepListBox  matlab.ui.control.ListBox
        ignorecaseListBox_5Label_2      matlab.ui.control.Label
        ToUpdateClass_intheir_mepListBox  matlab.ui.control.ListBox
        intheirListBox_8Label_2         matlab.ui.control.Label
        CurrentclassnamesPanel_2        matlab.ui.container.Panel
        Currentclassnames_mepTextArea   matlab.ui.control.TextArea
        ToUpdateClass_Updatecompolist_mepButton  matlab.ui.control.Button
        UpdateclassnamesLabel_2         matlab.ui.control.Label
        UpdatematerialnamesTab_2        matlab.ui.container.Tab
        CurrentmaterialnamesPanel_2     matlab.ui.container.Panel
        Currentmaterialnames_mepTextArea  matlab.ui.control.TextArea
        ToUpdateMaterial_writetheirmaterial_mepTextArea  matlab.ui.control.TextArea
        writerecognisablematerialnameinsequenceLabel_2  matlab.ui.control.Label
        ToUpdateMaterial_Foreachof_mepTextArea  matlab.ui.control.TextArea
        ForeachoftheseclassesTextArea_4Label  matlab.ui.control.Label
        ToUpdateMaterial_Updatecompolist_mepButton  matlab.ui.control.Button
        UpdatematerialnamesLabel_2      matlab.ui.control.Label
        FurtherprocesstheMEPcompolistLabel  matlab.ui.control.Label
        Directory_mepCompoEditField     matlab.ui.control.EditField
        DirectoryEditField_3Label       matlab.ui.control.Label
        UITable_mepCompo                matlab.ui.control.Table
        ReadMEPcomponentlistLabel       matlab.ui.control.Label
        ReadfileButton_mepCompo         matlab.ui.control.Button
        StructuralMEPdatabaseTab        matlab.ui.container.Tab
        Directory_databaseEditField     matlab.ui.control.EditField
        DirectoryEditField_6Label       matlab.ui.control.Label
        UITable_database                matlab.ui.control.Table
        DatabaseLabel_3                 matlab.ui.control.Label
        ReadfileButton_database         matlab.ui.control.Button
        SummaryTab                      matlab.ui.container.Tab
        MEPclassesEditField             matlab.ui.control.EditField
        MEPclassesEditFieldLabel        matlab.ui.control.Label
        StructuralclassesEditField      matlab.ui.control.EditField
        StructuralclassesEditFieldLabel  matlab.ui.control.Label
        TabData2                        matlab.ui.container.Tab
        RulesforfloorlevelidentificationofcompoLabel  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        ElevationdataTab                matlab.ui.container.Tab
        nMEditField                     matlab.ui.control.NumericEditField
        NumactivityexecutionmodesEditFieldLabel  matlab.ui.control.Label
        nREditField                     matlab.ui.control.NumericEditField
        NumtypesofresourcesEditFieldLabel  matlab.ui.control.Label
        ElevationExplanationLabel       matlab.ui.control.Label
        H0toHnTextArea                  matlab.ui.control.TextArea
        InputH_0toH_ninsequenceLabel    matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        FloorlevelofstructuralcompoTab  matlab.ui.container.Tab
        Floor_S2_areassignedto_structEditField  matlab.ui.control.NumericEditField
        areassignedtofloorlevelEditFieldLabel_2  matlab.ui.control.Label
        Floor_S2_upperend_structEditField  matlab.ui.control.NumericEditField
        upperendEditFieldLabel_2        matlab.ui.control.Label
        Floor_S2_and_structEditField    matlab.ui.control.NumericEditField
        andEditFieldLabel_2             matlab.ui.control.Label
        Floor_S2_lowerend_structEditField  matlab.ui.control.NumericEditField
        lowerendEditFieldLabel_2        matlab.ui.control.Label
        Floor_S2_andhas_structEditField  matlab.ui.control.NumericEditField
        andhasLabel_2                   matlab.ui.control.Label
        Floor_S2_Thosecompo_structDropDown  matlab.ui.control.DropDown
        ThosecompowhichareinclassDropDownLabel_2  matlab.ui.control.Label
        Floor_S2_structCheckBox         matlab.ui.control.CheckBox
        Floor_S1_areassignedto_structEditField  matlab.ui.control.NumericEditField
        areassignedtofloorlevelEditFieldLabel  matlab.ui.control.Label
        Floor_S1_upperend_structEditField  matlab.ui.control.NumericEditField
        upperendEditFieldLabel          matlab.ui.control.Label
        Floor_S1_and_structEditField    matlab.ui.control.NumericEditField
        andEditFieldLabel               matlab.ui.control.Label
        Floor_S1_lowerend_structEditField  matlab.ui.control.NumericEditField
        lowerendEditFieldLabel          matlab.ui.control.Label
        Floor_S1_andhas_structEditField  matlab.ui.control.NumericEditField
        andhasLabel                     matlab.ui.control.Label
        Floor_S1_Thosecompo_structDropDown  matlab.ui.control.DropDown
        ThosecompowhichareinclassDropDownLabel  matlab.ui.control.Label
        Floor_S1_structCheckBox         matlab.ui.control.CheckBox
        Specialcase_structLabel         matlab.ui.control.Label
        Compo9ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_17                    matlab.ui.control.RadioButton
        n1FButton_17                    matlab.ui.control.RadioButton
        nFButton_17                     matlab.ui.control.RadioButton
        ElevationExplanation_structLabel  matlab.ui.control.Label
        DeltaH_structEditField          matlab.ui.control.NumericEditField
        DeltaH_structEditFieldLabel     matlab.ui.control.Label
        SpecifywhichlevelthesecompoareassignedtoLabel  matlab.ui.control.Label
        Compo8ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_8                     matlab.ui.control.RadioButton
        n1FButton_8                     matlab.ui.control.RadioButton
        nFButton_8                      matlab.ui.control.RadioButton
        Compo7ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_7                     matlab.ui.control.RadioButton
        n1FButton_7                     matlab.ui.control.RadioButton
        nFButton_7                      matlab.ui.control.RadioButton
        Compo6ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_6                     matlab.ui.control.RadioButton
        n1FButton_6                     matlab.ui.control.RadioButton
        nFButton_6                      matlab.ui.control.RadioButton
        Compo5ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_5                     matlab.ui.control.RadioButton
        n1FButton_5                     matlab.ui.control.RadioButton
        nFButton_5                      matlab.ui.control.RadioButton
        Compo4ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_4                     matlab.ui.control.RadioButton
        n1FButton_4                     matlab.ui.control.RadioButton
        nFButton_4                      matlab.ui.control.RadioButton
        Compo3ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_3                     matlab.ui.control.RadioButton
        n1FButton_3                     matlab.ui.control.RadioButton
        nFButton_3                      matlab.ui.control.RadioButton
        Compo2ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton_2                     matlab.ui.control.RadioButton
        n1FButton_2                     matlab.ui.control.RadioButton
        nFButton_2                      matlab.ui.control.RadioButton
        Compo1ButtonGroup               matlab.ui.container.ButtonGroup
        n2FButton                       matlab.ui.control.RadioButton
        n1FButton                       matlab.ui.control.RadioButton
        nFButton                        matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        FloorlevelofMEPcompoTab         matlab.ui.container.Tab
        Compo9ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_18                    matlab.ui.control.RadioButton
        n1FButton_18                    matlab.ui.control.RadioButton
        nFButton_18                     matlab.ui.control.RadioButton
        ElevationExplanation_mepLabel   matlab.ui.control.Label
        DeltaH_mepEditField             matlab.ui.control.NumericEditField
        DeltaH_mepEditFieldLabel        matlab.ui.control.Label
        SpecifywhichlevelthesecompoareassignedtoLabel_2  matlab.ui.control.Label
        Compo8ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_16                    matlab.ui.control.RadioButton
        n1FButton_16                    matlab.ui.control.RadioButton
        nFButton_16                     matlab.ui.control.RadioButton
        Compo7ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_15                    matlab.ui.control.RadioButton
        n1FButton_15                    matlab.ui.control.RadioButton
        nFButton_15                     matlab.ui.control.RadioButton
        Compo6ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_14                    matlab.ui.control.RadioButton
        n1FButton_14                    matlab.ui.control.RadioButton
        nFButton_14                     matlab.ui.control.RadioButton
        Compo5ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_13                    matlab.ui.control.RadioButton
        n1FButton_13                    matlab.ui.control.RadioButton
        nFButton_13                     matlab.ui.control.RadioButton
        Compo4ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_12                    matlab.ui.control.RadioButton
        n1FButton_12                    matlab.ui.control.RadioButton
        nFButton_12                     matlab.ui.control.RadioButton
        Compo3ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_11                    matlab.ui.control.RadioButton
        n1FButton_11                    matlab.ui.control.RadioButton
        nFButton_11                     matlab.ui.control.RadioButton
        Compo2ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_10                    matlab.ui.control.RadioButton
        n1FButton_10                    matlab.ui.control.RadioButton
        nFButton_10                     matlab.ui.control.RadioButton
        Compo1ButtonGroup_2             matlab.ui.container.ButtonGroup
        n2FButton_9                     matlab.ui.control.RadioButton
        n1FButton_9                     matlab.ui.control.RadioButton
        nFButton_9                      matlab.ui.control.RadioButton
        Image2_2                        matlab.ui.control.Image
        ShowatomactivitiesTab           matlab.ui.container.Tab
        UITable_mepAtomAct              matlab.ui.control.Table
        Shownlistofatomactivities_mepButton  matlab.ui.control.Button
        UITable_structAtomAct           matlab.ui.control.Table
        Shownlistofatomactivities_structButton  matlab.ui.control.Button
        TabSeq                          matlab.ui.container.Tab
        SequencingrulesLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        RulesforstructuralcompoTab      matlab.ui.container.Tab
        beforeafterDropDown_S1_1        matlab.ui.control.DropDown
        shouldbeLabel                   matlab.ui.control.Label
        upperend_structEditField_S1_1   matlab.ui.control.NumericEditField
        upperendEditFieldLabel_3        matlab.ui.control.Label
        and_structEditField_S1_1        matlab.ui.control.NumericEditField
        andEditFieldLabel_3             matlab.ui.control.Label
        lowerend_structEditField_S1_1   matlab.ui.control.NumericEditField
        lowerendEditFieldLabel_3        matlab.ui.control.Label
        andhas_structEditField_S1_1     matlab.ui.control.NumericEditField
        andhasLabel_3                   matlab.ui.control.Label
        Specialcase1CheckBox            matlab.ui.control.CheckBox
        StructuralclassesEditField_2    matlab.ui.control.EditField
        StructuralclassesEditField_2Label  matlab.ui.control.Label
        Seq5DropDown                    matlab.ui.control.DropDown
        Seq5DropDownLabel               matlab.ui.control.Label
        atlevelDropDown_S1_1            matlab.ui.control.DropDown
        atlevelDropDown_2Label          matlab.ui.control.Label
        atlevelDropDown_S1_2            matlab.ui.control.DropDown
        atlevelDropDownLabel            matlab.ui.control.Label
        classDropDown_S1_2              matlab.ui.control.DropDown
        classDropDownLabel              matlab.ui.control.Label
        classDropDown_S1_1              matlab.ui.control.DropDown
        classDropDown_2Label            matlab.ui.control.Label
        Seq4DropDown                    matlab.ui.control.DropDown
        Seq4DropDownLabel               matlab.ui.control.Label
        Seq3DropDown                    matlab.ui.control.DropDown
        Seq3DropDownLabel               matlab.ui.control.Label
        Seq2DropDown                    matlab.ui.control.DropDown
        Seq2DropDownLabel               matlab.ui.control.Label
        Seq1DropDown                    matlab.ui.control.DropDown
        Seq1DropDownLabel               matlab.ui.control.Label
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        EssentialLabel                  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        optionalForthewholebuildingLabel  matlab.ui.control.Label
        WithineachfloorlevelLabel       matlab.ui.control.Label
        RulesforMEPcompoTab             matlab.ui.container.Tab
        MEPclassesEditField_2           matlab.ui.control.EditField
        MEPclassesEditFieldLabel_2      matlab.ui.control.Label
        A_smallEditField                matlab.ui.control.NumericEditField
        A_smallEditFieldLabel           matlab.ui.control.Label
        A_largeEditField                matlab.ui.control.NumericEditField
        A_largeEditFieldLabel           matlab.ui.control.Label
        L_shortEditField                matlab.ui.control.NumericEditField
        L_shortEditFieldLabel           matlab.ui.control.Label
        L_longEditField                 matlab.ui.control.NumericEditField
        L_longEditFieldLabel            matlab.ui.control.Label
        D_minv2EditField                matlab.ui.control.NumericEditField
        D_minv2EditFieldLabel           matlab.ui.control.Label
        D_minh2EditField                matlab.ui.control.NumericEditField
        D_minh2EditFieldLabel           matlab.ui.control.Label
        D_minCeilEditField              matlab.ui.control.NumericEditField
        D_minCeilEditFieldLabel         matlab.ui.control.Label
        D_minWallEditField              matlab.ui.control.NumericEditField
        D_minWallEditFieldLabel         matlab.ui.control.Label
        D_minvEditField                 matlab.ui.control.NumericEditField
        D_minvEditFieldLabel            matlab.ui.control.Label
        D_minhEditField                 matlab.ui.control.NumericEditField
        D_minhEditFieldLabel            matlab.ui.control.Label
        ChecklengthbeforeareaCheckBox   matlab.ui.control.CheckBox
        Image3                          matlab.ui.control.Image
        SizerelationslengthandcrosssectionalsizeLabel  matlab.ui.control.Label
        SpatialrelationsLabel           matlab.ui.control.Label
        TabPref                         matlab.ui.container.Tab
        ResourceavailabilityPanel       matlab.ui.container.Panel
        Numres3Spinner                  matlab.ui.control.Spinner
        Numres3SpinnerLabel             matlab.ui.control.Label
        Numres2Spinner                  matlab.ui.control.Spinner
        Numres2SpinnerLabel             matlab.ui.control.Label
        Numres1Spinner                  matlab.ui.control.Spinner
        Numres1SpinnerLabel             matlab.ui.control.Label
        NumresourcetypesEditField_3     matlab.ui.control.EditField
        NumresourcetypesEditField_3Label  matlab.ui.control.Label
        OptimisationoptionsButtonGroup  matlab.ui.container.ButtonGroup
        onlywhenincrementofzonedimension0alongxandyLabel  matlab.ui.control.Label
        fornormalisationifknownotherwiseleaveboth0Label  matlab.ui.control.Label
        whennvarsisverylargeornearoptimalsolutionisnotrequiredLabel  matlab.ui.control.Label
        Generateinitialsolutiononlywithw21CheckBox  matlab.ui.control.CheckBox
        MaxtimeperruninsEditField       matlab.ui.control.NumericEditField
        MaxtimeperruninsEditField_2Label  matlab.ui.control.Label
        NumsetsEditField                matlab.ui.control.NumericEditField
        NumsetsEditFieldLabel           matlab.ui.control.Label
        Weightofobj2w21w1Label          matlab.ui.control.Label
        TimeunitoftheproblemDropDown    matlab.ui.control.DropDown
        TimeunitoftheproblemDropDownLabel  matlab.ui.control.Label
        Weightofobj1w1EditField         matlab.ui.control.NumericEditField
        Weightofobj1w1EditFieldLabel    matlab.ui.control.Label
        Factorforobj2EditField          matlab.ui.control.NumericEditField
        Factorforobj2Label              matlab.ui.control.Label
        Obj1totaldirectcostObj2totaldurationLabel  matlab.ui.control.Label
        Factorforobj1EditField          matlab.ui.control.NumericEditField
        Factorforobj1Label              matlab.ui.control.Label
        IfaprioriButton                 matlab.ui.control.RadioButton
        IfaposterioriButton             matlab.ui.control.RadioButton
        WorkingzonesinmetresPanel       matlab.ui.container.Panel
        IncrementofzonedimensionalongyEditField  matlab.ui.control.NumericEditField
        alongyLabel                     matlab.ui.control.Label
        IncrementofzonedimensionalongxEditField  matlab.ui.control.NumericEditField
        IncrementofzonedimensionalongxLabel  matlab.ui.control.Label
        MaxzonesizealongyEditField      matlab.ui.control.NumericEditField
        alongyEditField_6Label          matlab.ui.control.Label
        MaxzonesizealongxEditField      matlab.ui.control.NumericEditField
        MaxzonesizealongxEditField_2Label  matlab.ui.control.Label
        MinzonesizealongyEditField      matlab.ui.control.NumericEditField
        alongyEditField_5Label          matlab.ui.control.Label
        MinzonesizealongxEditField      matlab.ui.control.NumericEditField
        MinzonesizealongxEditField_2Label  matlab.ui.control.Label
        thenalongwhichaxisfirstDropDown  matlab.ui.control.DropDown
        thenalongwhichaxisfirstDropDownLabel  matlab.ui.control.Label
        ConstructionstartingfromwhichcornerDropDown  matlab.ui.control.DropDown
        ConstructionstartingfromwhichcornerDropDownLabel  matlab.ui.control.Label
        BuildingsizealongymEditField    matlab.ui.control.EditField
        alongymEditFieldLabel           matlab.ui.control.Label
        BuildingsizealongxmEditField    matlab.ui.control.EditField
        BuildingsizealongxmEditFieldLabel  matlab.ui.control.Label
        TabOpt                          matlab.ui.container.Tab
        NumrunsofoptimisationEditField  matlab.ui.control.NumericEditField
        NumrunsofoptimisationEditField_2Label  matlab.ui.control.Label
        NumtimeunitsestEditField        matlab.ui.control.NumericEditField
        NumtimeunitsestEditField_2Label  matlab.ui.control.Label
        NumdaysestEditField             matlab.ui.control.NumericEditField
        NumdaysestEditField_2Label      matlab.ui.control.Label
        NumresourcetypesEditField_2     matlab.ui.control.NumericEditField
        NumresourcetypesEditField_4Label  matlab.ui.control.Label
        NumactmodesEditField            matlab.ui.control.NumericEditField
        NumactmodesEditField_2Label     matlab.ui.control.Label
        NumactivitiesEditField          matlab.ui.control.NumericEditField
        NumactivitiesEditField_2Label   matlab.ui.control.Label
        NumfloorsEditField_2            matlab.ui.control.NumericEditField
        NumfloorsEditField_3Label       matlab.ui.control.Label
        MaxnumzonesalongyEditField      matlab.ui.control.NumericEditField
        alongyEditField_6Label_2        matlab.ui.control.Label
        MaxnumzonesalongxEditField      matlab.ui.control.NumericEditField
        MaxnumzonesalongxEditField_2Label  matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        ActivityclusterssampleTab       matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        structuralactivityclustersTab   matlab.ui.container.Tab
        UITable_tableZoned_struct       matlab.ui.control.Table
        MEPactivityclustersTab          matlab.ui.container.Tab
        UITable_tableZoned_mep          matlab.ui.control.Table
        MaxtimecompletingallrunsinminEditField  matlab.ui.control.EditField
        MaxtimecompletingallrunsinminLabel  matlab.ui.control.Label
        NumvariablesnvarsSuggestedtobe6000EditField  matlab.ui.control.EditField
        NumvariablesnvarsSuggestedtobe6000EditFieldLabel  matlab.ui.control.Label
        EstimateoptimisationparametersButton  matlab.ui.control.Button
        CheckproblemsettingsandrunLabel  matlab.ui.control.Label
        TabResult                       matlab.ui.container.Tab
        takestimebeforetheresultisshownLabel  matlab.ui.control.Label
        TabGroup8                       matlab.ui.container.TabGroup
        ResultaposterioriTab            matlab.ui.container.Tab
        UITable_resultIfAPosteriori     matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        ResultaprioriTab                matlab.ui.container.Tab
        UITable_resultIfAPriori         matlab.ui.control.Table
        ProjectstartingdateDatePicker   matlab.ui.control.DatePicker
        ProjectstartingdateDatePickerLabel  matlab.ui.control.Label
        ExportScheduleDirectoryEditField  matlab.ui.control.EditField
        DirectoryEditField_5Label       matlab.ui.control.Label
        ExportthescheduleforapriorimethodLabel  matlab.ui.control.Label
        ExportasXlsxFileBtn             matlab.ui.control.Button
        RunoptimisationshowandsaveresultsLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        TochangeunitfrommtommMenu_2     matlab.ui.container.Menu
        TochangeunitfrommtommMenu       matlab.ui.container.Menu
        TokeepdiscardcompoMenu          matlab.ui.container.Menu
    end


properties (Access = public)
    tableCompo_struct;  tableCompo_mep; tableCompo_struct_unitInMetre_bool;
    floorElevation;  nLevel;
    tableActivity_struct; tableActivity_mep;
    nR; nM; nA;
    tableZoned_struct; tableZoned_mep;
    nA_struct; nA_mep;
    nD_struct; nD_mep;
    nvars_struct; nvars_mep;
    building_x_min; building_y_min; building_z_min; building_x_max; building_y_max; building_z_max;
    Zx; Zy; nZ_x; nZ_y; nZ;
    Zx_min; Zy_min; nZ_x_min; nZ_y_min; nZ_min;
    Zx_max; Zy_max; nZ_x_max; nZ_y_max; nZ_max;
    Zx_incre; Zy_incre;
    classEssential_struct; classNonEssential_struct; classSeq_struct;
    
    tableDatabase;
    timeUnitConver;
    
    SpecSeqName; SpecSeqName_class; SpecSeqName_countNonUndefined;
    bottom2top_bool; left2right_bool; verticleDirFirst_bool;
    classToSeq_S1_1; classToSeq_S1_2; levelToSeq_S1_1; levelToSeq_S1_2; beforeAfter_bool;
    seq_pred; seq_succ;
    
    tableZoned_mep_splitted; tableCompo_mep2;
    Qrdcoeff_struct; Qrdcoeff_mep; optMaxTime; onlyX0_bool;
    coeffDura; coeffCost; normalisationFactor_objCost; normalisationFactor_objDura; options;
    totalNumRun; Zxy_record; result_record;
            
    sol;
    
    %{
    matrix compoLevelScenarios: each row records one scenario
    each 3-number series records:
        (the lower/upper end is) lessThan (the elevation of) levelToCompare, minusDeltaH
        lessThan:           1 for lessThan, -1 for largerThan, 0 if no such condition needed
        levelToCompare:     0 or 1
        minusDeltaH:        1 if the elevation should - deltaH, 0 if no need to - deltaH
    examples:
    scenario 2:
        (LOWER END) <, (lev+0)F, -deltaH,   ((no > condition)),   (UPPER END) <, (lev+0)F, -0,       >, (lev+0)F, -deltaH, 
                    1,    0,    1,           0,    0,    0,                   1,    0,    0,          -1,     0,     1
    scenario 4:
        (LOWER END) <, (lev+0)F, -0,      >, (lev+0)F, -deltaH,   (UPPER END) <, (lev+1)F, -deltaH,   >, (lev+1)F, -deltaH, 
                    1,    0,    0,          -1,    0,    1,                   1,    1,    1,          -1,     1,     1
    %}
    compoLevelScenarios = [ ...
                1,  0,  1,    0,  0,  0,      1,  0,  1,    0,  0,  0;
                1,  0,  1,    0,  0,  0,      1,  0,  0,   -1,  0,  1;
                1,  0,  1,    0,  0,  0,      1,  1,  1,   -1,  0,  0;
                1,  0,  0,   -1,  0,  1,      1,  1,  1,   -1,  0,  0;
                1,  0,  1,    0,  0,  0,      1,  1,  0,   -1,  1,  1;
                1,  0,  0,   -1,  0,  1,      1,  1,  0,   -1,  1,  1;
                1,  0,  0,   -1,  0,  1,      0,  0,  0,   -1,  1,  0;
                1,  0,  1,    0,  0,  0,      0,  0,  0,   -1,  1,  0;
                1,  1,  0,   -1,  1,  1,      1,  1,  0,   -1,  1,  1];
    
    nAllEleGuid = 20;  % 20 is enough for the case study; can be changed by user if needed
    Dmin_h; Dmin_v; Dwallmin; Dceilmin; Dmin_h2; Dmin_v2; D_length_long; D_length_short; D_crosssec_large; D_crosssec_small;
end

methods (Access = public)
    % ===== func_updateCompoClassNameInAllTextArea =================================
    function [] = func_updateCompoClassNameInAllTextArea_struct(app, ... % function to update class names in all text area
            tableCompo)
        app.Currentclassnames_structTextArea.Value = unique(tableCompo.Class);
        % sequencing rules within floor level
        app.Seq1DropDown.Items = [categorical(" - "), unique(tableCompo.Class)'];
        app.Seq2DropDown.Items = app.Seq1DropDown.Items;
        app.Seq3DropDown.Items = app.Seq1DropDown.Items;
        app.Seq4DropDown.Items = app.Seq1DropDown.Items;
        app.Seq5DropDown.Items = app.Seq1DropDown.Items;
        % when modifying level in special cases
        app.Floor_S1_Thosecompo_structDropDown.Items = app.Seq1DropDown.Items; 
        app.Floor_S2_Thosecompo_structDropDown.Items = app.Seq1DropDown.Items; 
        % sequencing rules for the whole building
        app.classDropDown_S1_1.Items = app.Seq1DropDown.Items; 
        app.classDropDown_S1_2.Items = app.Seq1DropDown.Items; 
        app.StructuralclassesEditField.Value = strjoin(string(unique(tableCompo.Class)'),', ');  % join into one string
        app.StructuralclassesEditField_2.Value = strjoin(string(unique(tableCompo.Class)'),', ');
    end
    % ===== func_updateCompoClassNameInAllTextArea =================================
    function [] = func_updateCompoClassNameInAllTextArea_mep(app, ... % function to update class names in all text area
            tableCompo)
        app.MEPclassesEditField.Value = strjoin(string(unique(tableCompo.Class)'),', ');  % join into one string
        app.MEPclassesEditField_2.Value = strjoin(string(unique(tableCompo.Class)'),', ');
    end
    
    % ===== func_updateTableCompo_toKeepDiscard ====================================
    function tableCompo_updated = func_updateTableCompo_toKeepDiscard(app, ... % function to process the options selected in UI
            tableCompo, tokeepdiscard_struct_rule2ListBox_cell, ... 
            tokeepdiscard_thosecompowith_struct_ruleTextArea_cellArr, ...
            tokeepdiscard_intheir_struct_ruleListBox_cellArr, tokeepdiscard_ignorecase_struct_ruleListBox_char)
        % "to keep/discard ..."
        if contains(tokeepdiscard_struct_rule2ListBox_cell, "keep", "IgnoreCase",true)
            toKeep_bool = true;
        else 
            toKeep_bool = false;
        end
        % "... those compo with the following words ..."
        compoClass_asCompoName_struct = string(tokeepdiscard_thosecompowith_struct_ruleTextArea_cellArr);
        % "... in their ..."
        inWhichColumn = string(tokeepdiscard_intheir_struct_ruleListBox_cellArr);
        % "... and ignore case or not..."
        if tokeepdiscard_ignorecase_struct_ruleListBox_char == 'Y'
            toIgnoreCase_bool = true;
        else 
            toIgnoreCase_bool = false;
        end
        % keep/discard the compo
        toSelect = contains(string(tableCompo{:, inWhichColumn}), compoClass_asCompoName_struct, ...
            'IgnoreCase', toIgnoreCase_bool);
        if toKeep_bool == true
            tableCompo(~toSelect,:) = [];
        else
            tableCompo(toSelect,:) = [];
        end
        tableCompo_updated = tableCompo;
    end
    
    % ===== func_updateTableCompo_toUpdateClass ====================================
    function tableCompo_updated = func_updateTableCompo_toUpdateClass(app, ... % function to process the options selected in UI
            tableCompo, toUpdateClass_Forcompowith_structTextArea_cellArr, ... 
            toUpdateClass_intheir_structListBox_cell, ...
            toUpdateClass_ignorecase_structListBox_char, toUpdateClass_writerecognisable_structTextArea_cellArr)
        % "for compo with these words ..."
        % compoClass_asCompoName_struct = string(toUpdateClass_Forcompowith_structTextArea_cellArr);
        % "... in their ..."
        inWhichColumn = string(toUpdateClass_intheir_structListBox_cell);
        % "... ignore case or not..."
        if toUpdateClass_ignorecase_structListBox_char == 'Y'
            toIgnoreCase_bool = true;
        else 
            toIgnoreCase_bool = false;
        end
        % "... , write recognisable class name (in sequence) ..."
        % compoClass_asCompoName_struct = string(toUpdateClass_writerecognisable_structTextArea_cellArr);
        
        % update the class names
        for i = 1:length(toUpdateClass_Forcompowith_structTextArea_cellArr)
            toSelect = contains(tableCompo{:, inWhichColumn}, string(toUpdateClass_Forcompowith_structTextArea_cellArr(i)),...
                'IgnoreCase', toIgnoreCase_bool);
            % tableCompo{toSelect, 'Class'} = compoClass_recognisable(i);
            tableCompo{toSelect, 'Class'} = categorical(string(toUpdateClass_writerecognisable_structTextArea_cellArr(i)));
        end
        tableCompo_updated = tableCompo;
    end
    
    
    % ===== func_updateTableCompo_toUpdateMaterial =================================
    function tableCompo_updated = func_updateTableCompo_toUpdateMaterial(app, ... % function to process the options selected in UI
            tableCompo, toUpdateMaterial_Foreachof_structTextArea_cellArr, ... 
            toUpdateMaterial_writetheirmaterial_structTextArea_cellArr)
        % "For each of these classes ..."
        % "... write recognisable material name  ..."
        % update the material names
        for i = 1:length(toUpdateMaterial_Foreachof_structTextArea_cellArr)
            toSelect = tableCompo{:, 'Class'} == categorical(string(toUpdateMaterial_Foreachof_structTextArea_cellArr(i)));
            tableCompo{toSelect, 'C_Material'} = categorical(string(toUpdateMaterial_writetheirmaterial_structTextArea_cellArr(i)));
        end
        tableCompo_updated = tableCompo;
    end
    
    % ===== func_identifyCompoLevel ================================================
    function tableCompo = func_identifyCompoLevel(app, ...  % identify the base level of a compo
            tableCompo, floorElevation, deltaH, nLevel, ...
            compoLevelScenarios, s, nF_bool, n1F_bool)
        % add virtual top elev of roof so floor level range can be -1 to 2
        floorElevation = [floorElevation; floorElevation(end)+10000];  
        for lev = nLevel:-1:1  % lev = 1:3, floorElevation(1 to 4)
            % < if compoLevelScenarios(1)==1, > if ==-1;
            % non selected if ==0 w/o equal sign, all selected if ==0 w/ equal sign
            toSelect1 = tableCompo{:,"Z_min"} * compoLevelScenarios(s,1) <= ...
                compoLevelScenarios(s,1) * (floorElevation(lev + compoLevelScenarios(s,2)) - compoLevelScenarios(s,3) * deltaH );
            toSelect2 = tableCompo{:,"Z_min"} * compoLevelScenarios(s,4) <= ...
                compoLevelScenarios(s,4) * (floorElevation(lev + compoLevelScenarios(s,5)) - compoLevelScenarios(s,6) * deltaH );
            toSelect3 = tableCompo{:,"Z_max"} * compoLevelScenarios(s,7) <= ...
                compoLevelScenarios(s,7) * (floorElevation(lev + compoLevelScenarios(s,8)) - compoLevelScenarios(s,9) * deltaH );
            toSelect4 = tableCompo{:,"Z_max"} * compoLevelScenarios(s,10) <= ...
                compoLevelScenarios(s,10) * (floorElevation(lev + compoLevelScenarios(s,11)) - compoLevelScenarios(s,12) * deltaH );
            toSelect = toSelect1 & toSelect2 & toSelect3 & toSelect4;  % all compo satisfying the lower & upper end conditions
            if nF_bool == true
                nF = 0;
            elseif n1F_bool == true
                nF = 1;
            else
                nF = 2;
            end
            tableCompo{toSelect, 'BaseLevel'} = strcat("0", num2str(lev-2 + nF));  % GF: 0F; lev=4: 2F
        end       
                
    end
    
    % ===== func_modifyTableCompoActivity_toModifyLevel =================================
    function [tableCompo] = ...
            func_modifyTableCompoActivity_toModifyLevel(app, ... % function to modify level for special cases
                tableCompo, ...
                classToModify_S1, lowerEnd_lb_S1, lowerEnd_ub_S1, ...
                upperEnd_lb_S1, upperEnd_ub_S1, levelToAssign_S1)
        % "For each of these classes ..."
        % "... write recognisable material name  ..."
        % update the material names
        toSelect1 = tableCompo{:, 'Class'} == categorical(string(classToModify_S1));
        toSelect2 = tableCompo{:, 'Z_min'} >= lowerEnd_lb_S1;
        toSelect3 = tableCompo{:, 'Z_min'} <= lowerEnd_ub_S1;
        toSelect4 = tableCompo{:, 'Z_max'} >= upperEnd_lb_S1;
        toSelect5 = tableCompo{:, 'Z_max'} <= upperEnd_ub_S1;
        toSelect = toSelect1 & toSelect2 & toSelect3 & toSelect4 & toSelect5;
        tableCompo{toSelect, 'BaseLevel'} = strcat("0", num2str(levelToAssign_S1));
        %tableActivity{toSelect, 'BaseLevel'} = strcat("0", num2str(levelToAssign_S1));
    end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadfileButton_database
        function ReadfileButton_structdatabasePushed(app, event)
            directory_database = app.Directory_databaseEditField.Value;
            app.tableDatabase = func_importDatabaseFile(directory_database,1);
            app.UITable_database.ColumnName = app.tableDatabase.Properties.VariableNames;
            app.UITable_database.Data = app.tableDatabase;
            app.UITable_database.RowName = 'numbered';


        end

        % Button pushed function: ReadfileButton_structCompo
        function ReadfileButton_structCompoPushed(app, event)
            % read struct compo list
            directory_struct_Compo = app.Directory_structCompoEditField.Value;
            app.tableCompo_struct = func_importComponentFile(directory_struct_Compo,1);
            % To round numbers to integers (mm), and caluclate the min & max coord of each component % rounding omitted
            app.tableCompo_struct = func_preprocesCompoTable(app.tableCompo_struct);

            app.UITable_structCompo.ColumnName = app.tableCompo_struct.Properties.VariableNames;
            app.UITable_structCompo.Data = app.tableCompo_struct;
            app.UITable_structCompo.RowName = 'numbered';
            
            % print unique class names in TextArea
            % app.Currentclassnames_structTextArea.Value = unique(app.tableCompo_struct.Class);
            func_updateCompoClassNameInAllTextArea_struct(app, ... % function to update class names in all text area
                app.tableCompo_struct);
            % print unique material names in TextArea
            app.Currentmaterialnames_structTextArea.Value = unique(app.tableCompo_struct.C_Material);
            
            % Find the min & max coordinates of the whole building (in m)
            app.building_x_min = min(app.tableCompo_struct{:,"X_min"}) /1000;
            app.building_y_min = min(app.tableCompo_struct{:,"Y_min"}) /1000;
            app.building_z_min = min(app.tableCompo_struct{:,"Z_min"}) /1000;
            app.building_x_max = max(app.tableCompo_struct{:,"X_max"}) /1000;
            app.building_y_max = max(app.tableCompo_struct{:,"Y_max"}) /1000;
            app.building_z_max = max(app.tableCompo_struct{:,"Z_max"}) /1000;
            app.BuildingsizealongxmEditField.Value = string(app.building_x_max - app.building_x_min);
            app.BuildingsizealongymEditField.Value = string(app.building_y_max - app.building_y_min);
        end

        % Button pushed function: ReadfileButton_mepCompo
        function ReadfileButton_mepCompoPushed(app, event)
            % read mep compo list
            directory_mep_Compo = app.Directory_mepCompoEditField.Value;
            app.tableCompo_mep = func_importComponentFile(directory_mep_Compo,1);
            % To round numbers to integers (mm), and caluclate the min & max coord of each component % rounding omitted
            app.tableCompo_mep = func_preprocesCompoTable(app.tableCompo_mep);
            
            app.UITable_mepCompo.ColumnName = app.tableCompo_mep.Properties.VariableNames;
            app.UITable_mepCompo.Data = app.tableCompo_mep;
            app.UITable_mepCompo.RowName = 'numbered';
            
            % print unique class names in TextArea
            app.Currentclassnames_mepTextArea.Value = unique(app.tableCompo_mep.Class);
            % print unique material names in TextArea
            app.Currentmaterialnames_mepTextArea.Value = unique(app.tableCompo_mep.C_Material);
            func_updateCompoClassNameInAllTextArea_mep(app, ... % function to update class names in all text area
                app.tableCompo_mep)
        end

        % Button pushed function: 
        % Updatecompolist_struct_changeUnitButton
        function Updatecompolist_struct_changeUnitButtonPushed(app, event)
            app.tableCompo_struct_unitInMetre_bool = false;
            if app.Needtoconvertfrommtomm_structSwitch.Value == 'Y'
                app.tableCompo_struct_unitInMetre_bool = true;
            end
            if app.tableCompo_struct_unitInMetre_bool == true
                % for structural compo list: change unit from m to mm
                toChangeUnit = string(app.Converttheunitsofthesevalues_structListBox.Value);
                toChangeUnit = [toChangeUnit, "X_min","X_max","Y_min","Y_max","Z_min","Z_max"];
                for i = 1:length(toChangeUnit)
                    app.tableCompo_struct{:,toChangeUnit(i)} = app.tableCompo_struct{:,toChangeUnit(i)}*1000;
                end
                
                % update compo list
                app.UITable_structCompo.Data = app.tableCompo_struct;
            
                % Find the min & max coordinates of the whole building (in m)
                app.building_x_min = app.building_x_min *1000;
                app.building_y_min = app.building_y_min *1000;
                app.building_z_min = app.building_z_min *1000;
                app.building_x_max = app.building_x_max *1000;
                app.building_y_max = app.building_y_max *1000;
                app.building_z_max = app.building_z_max *1000;
                app.BuildingsizealongxmEditField.Value = string(app.building_x_max - app.building_x_min);
                app.BuildingsizealongymEditField.Value = string(app.building_y_max - app.building_y_min);
            end
        end

        % Button pushed function: 
        % Updatecompolist_mep_changeUnitButton
        function Updatecompolist_mep_changeUnitButtonPushed(app, event)
            if app.Needtoconvertfrommtomm_mepSwitch.Value == 'Y'
                % for mep compo list: change unit from m to mm
                toChangeUnit = string(app.Converttheunitsofthesevalues_mepListBox.Value);
                toChangeUnit = [toChangeUnit, "X_min","X_max","Y_min","Y_max","Z_min","Z_max"];
                for i = 1:length(toChangeUnit)
                    app.tableCompo_mep{:,toChangeUnit(i)} = app.tableCompo_mep{:,toChangeUnit(i)}*1000;
                end
                
                % update compo list
                app.UITable_mepCompo.Data = app.tableCompo_mep;
            end
        end

        % Button pushed function: 
        % Tokeepdiscard_Updatecompolist_structButton
        function Tokeepdiscard_Updatecompolist_structButtonPushed(app, event)
            % apply filtering rule 1 ===============================================
            if app.Tokeepdiscard_Applyfilteringrule1_structCheckBox.Value == true
                app.tableCompo_struct = func_updateTableCompo_toKeepDiscard(app, ... % function to process the options selected in UI
                    app.tableCompo_struct, app.Tokeepdiscard_struct_rule1ListBox.Value, ...
                    app.Tokeepdiscard_thosecompowith_struct_rule1TextArea.Value, ...
                    app.Tokeepdiscard_intheir_struct_rule1ListBox.Value, app.Tokeepdiscard_ignorecase_struct_rule1ListBox.Value);
            end
            % apply filtering rule 2 ===============================================
            if app.Tokeepdiscard_Applyfilteringrule2_structCheckBox.Value == true
                app.tableCompo_struct = func_updateTableCompo_toKeepDiscard(app, ... % function to process the options selected in UI
                    app.tableCompo_struct, app.Tokeepdiscard_struct_rule2ListBox.Value, ...
                    app.Tokeepdiscard_thosecompowith_struct_rule2TextArea.Value, ...
                    app.Tokeepdiscard_intheir_struct_rule2ListBox.Value, app.Tokeepdiscard_ignorecase_struct_rule2ListBox.Value);
            end
            % update compo list
            app.UITable_structCompo.Data = app.tableCompo_struct;
            % print unique class names
            func_updateCompoClassNameInAllTextArea_struct(app, ... % function to update class names in all text area
                app.tableCompo_struct);
            % print unique material names in TextArea
            app.Currentmaterialnames_structTextArea.Value = unique(app.tableCompo_struct.C_Material);
            
            % Find the min & max coordinates of the whole building (in m)
            if app.tableCompo_struct_unitInMetre_bool == true
                app.building_x_min = min(app.tableCompo_struct{:,"X_min"}) /1000;
                app.building_y_min = min(app.tableCompo_struct{:,"Y_min"}) /1000;
                app.building_z_min = min(app.tableCompo_struct{:,"Z_min"}) /1000;
                app.building_x_max = max(app.tableCompo_struct{:,"X_max"}) /1000;
                app.building_y_max = max(app.tableCompo_struct{:,"Y_max"}) /1000;
                app.building_z_max = max(app.tableCompo_struct{:,"Z_max"}) /1000;
                app.BuildingsizealongxmEditField.Value = string(app.building_x_max - app.building_x_min);
                app.BuildingsizealongymEditField.Value = string(app.building_y_max - app.building_y_min);
            end
        end

        % Button pushed function: 
        % Tokeepdiscard_Updatecompolist_mepButton
        function Tokeepdiscard_Updatecompolist_mepButtonPushed(app, event)
            % apply filtering rule 1 ===============================================
            if app.Tokeepdiscard_Applyfilteringrule1_mepCheckBox.Value == true
                app.tableCompo_mep = func_updateTableCompo_toKeepDiscard(app, ... % function to process the options selected in UI
                    app.tableCompo_mep, app.Tokeepdiscard_mep_rule1ListBox.Value, ...
                    app.Tokeepdiscard_thosecompowith_mep_rule1TextArea.Value, ...
                    app.Tokeepdiscard_intheir_mep_rule1ListBox.Value, app.Tokeepdiscard_ignorecase_mep_rule1ListBox.Value);
            end
            % apply filtering rule 2 ===============================================
            if app.Tokeepdiscard_Applyfilteringrule2_mepCheckBox.Value == true
                app.tableCompo_mep = func_updateTableCompo_toKeepDiscard(app, ... % function to process the options selected in UI
                    app.tableCompo_mep, app.Tokeepdiscard_mep_rule2ListBox.Value, ...
                    app.Tokeepdiscard_thosecompowith_mep_rule2TextArea.Value, ...
                    app.Tokeepdiscard_intheir_mep_rule2ListBox.Value, app.Tokeepdiscard_ignorecase_mep_rule2ListBox.Value);
            end
            % update compo list
            app.UITable_mepCompo.Data = app.tableCompo_mep;    
            % print unique class names in TextArea
            app.Currentclassnames_mepTextArea.Value = unique(app.tableCompo_mep.Class);
            % print unique material names in TextArea
            app.Currentmaterialnames_mepTextArea.Value = unique(app.tableCompo_mep.C_Material);
            func_updateCompoClassNameInAllTextArea_mep(app, ... % function to update class names in all text area
                app.tableCompo_mep)
        end

        % Button pushed function: 
        % ToUpdateClass_Updatecompolist_structButton
        function ToUpdateClass_Updatecompolist_structButtonPushed(app, event)
            % update class name, struct ===============================================
            app.tableCompo_struct = func_updateTableCompo_toUpdateClass(app, ... % function to process the options selected in UI
                app.tableCompo_struct, app.ToUpdateClass_Forcompowith_structTextArea.Value, ... 
                app.ToUpdateClass_intheir_structListBox.Value, ...
                app.ToUpdateClass_ignorecase_structListBox.Value, app.ToUpdateClass_writerecognisable_structTextArea.Value);
            % update compo list
            app.UITable_structCompo.Data = app.tableCompo_struct;
            % print unique class names in TextArea
            func_updateCompoClassNameInAllTextArea_struct(app, ... % function to update class names in all text area
                app.tableCompo_struct);
            % Find the min & max coordinates of the whole building (in m)
            if app.tableCompo_struct_unitInMetre_bool == true
                app.building_x_min = min(app.tableCompo_struct{:,"X_min"}) /1000;
                app.building_y_min = min(app.tableCompo_struct{:,"Y_min"}) /1000;
                app.building_z_min = min(app.tableCompo_struct{:,"Z_min"}) /1000;
                app.building_x_max = max(app.tableCompo_struct{:,"X_max"}) /1000;
                app.building_y_max = max(app.tableCompo_struct{:,"Y_max"}) /1000;
                app.building_z_max = max(app.tableCompo_struct{:,"Z_max"}) /1000;
                app.BuildingsizealongxmEditField.Value = string(app.building_x_max - app.building_x_min);
                app.BuildingsizealongymEditField.Value = string(app.building_y_max - app.building_y_min);
            end
        end

        % Button pushed function: 
        % ToUpdateClass_Updatecompolist_mepButton
        function ToUpdateClass_Updatecompolist_mepButtonPushed(app, event)
            % update class name, mep ===============================================
            app.tableCompo_mep = func_updateTableCompo_toUpdateClass(app, ... % function to process the options selected in UI
                app.tableCompo_mep, app.ToUpdateClass_Forcompowith_mepTextArea.Value, ... 
                app.ToUpdateClass_intheir_mepListBox.Value, ...
                app.ToUpdateClass_ignorecase_mepListBox.Value, app.ToUpdateClass_writerecognisable_mepTextArea.Value);
            % update compo list
            app.UITable_mepCompo.Data = app.tableCompo_mep;
            % print unique class names in TextArea
            app.Currentclassnames_mepTextArea.Value = unique(app.tableCompo_mep.Class);
            func_updateCompoClassNameInAllTextArea_mep(app, ... % function to update class names in all text area
                app.tableCompo_mep)
        end

        % Button pushed function: 
        % ToUpdateMaterial_Updatecompolist_structButton
        function ToUpdateMaterial_Updatecompolist_structButtonPushed(app, event)
            % update material name, struct ===============================================
            app.tableCompo_struct = func_updateTableCompo_toUpdateMaterial(app, ... % function to process the options selected in UI
                app.tableCompo_struct, app.ToUpdateMaterial_Foreachof_structTextArea.Value, ... 
                app.ToUpdateMaterial_writetheirmaterial_structTextArea.Value);
            % update compo list
            app.UITable_structCompo.Data = app.tableCompo_struct;
            % print unique material names in TextArea
            app.Currentmaterialnames_structTextArea.Value = unique(app.tableCompo_struct.C_Material);
        end

        % Button pushed function: 
        % ToUpdateMaterial_Updatecompolist_mepButton
        function ToUpdateMaterial_Updatecompolist_mepButtonPushed(app, event)
            % update material name, mep ===============================================
            app.tableCompo_mep = func_updateTableCompo_toUpdateMaterial(app, ... % function to process the options selected in UI
                app.tableCompo_mep, app.ToUpdateMaterial_Foreachof_mepTextArea.Value, ... 
                app.ToUpdateMaterial_writetheirmaterial_mepTextArea.Value);
            % update compo list
            app.UITable_mepCompo.Data = app.tableCompo_mep;
            % print unique material names in TextArea
            app.Currentmaterialnames_mepTextArea.Value = unique(app.tableCompo_mep.C_Material);
        end

        % Callback function
        function Confirm_elevationButtonPushed(app, event)
            app.floorElevation = str2double(app.H0toHnTextArea.Value);
            app.nLevel = length(app.floorElevation);
            
            app.nR = app.nREditField.Value;
            app.nM = app.nMEditField.Value;
        end

        % Button pushed function: 
        % Shownlistofatomactivities_structButton
        function Shownlistofatomactivities_structButtonPushed(app, event)

            % get elevation input
            app.floorElevation = str2double(app.H0toHnTextArea.Value);
            app.nLevel = length(app.floorElevation);
            app.nR = app.nREditField.Value;
            app.nM = app.nMEditField.Value;
            % show floor level in dropdown list in sequencing rules for whole building
            app.atlevelDropDown_S1_1.Items = string(0:1:app.nLevel-1)';
            app.atlevelDropDown_S1_2.Items = string(0:1:app.nLevel-1)';
            
            
            % judge the level of all compo and special compo
            deltaH_struct = app.DeltaH_structEditField.Value;
            % Clear all the baselevel first
            app.tableCompo_struct{:, 'BaseLevel'} = repmat("", height(app.tableCompo_struct), 1);
            
%             % Judge the level: "Pile", "FoundationBeam", "Betonaanstort", "kanaalplaat": assign all to level "-1"
%             for class = 1:4
%                 isInClass = tableCompo_struct{:,'Class'} == compoClass_struct(class);
%                 tableCompo_struct{isInClass, 'BaseLevel'} = "-1";
%             end
%             % Judge the level: "Column", "Beam", "Slab", "Wall", "Roof"
%             levelTol_1 = 1500;
%             for class = length(compoClass_struct):-1:5
%                 isInClass = tableCompo_struct{:,'Class'} == compoClass_struct(class);
%                 for lev = 1:nLevel
%                     isWithinLevelTol = abs(tableCompo_struct{:,"Z_max"} - floorElevation(lev)) <= levelTol_1;
%                     selectedRow = isInClass & isWithinLevelTol;
%                     tableCompo_struct{selectedRow, 'BaseLevel'} = strcat("0",num2str(lev-2));  % GF: 0F; lev=4: 2F
%                 end
%             end
%             
%             % Judge the level - special
%             % Judge the level: load bearing walls (probably)
%             % filtered by Size_Z
%             isInClass = app.tableCompo_struct{:,'Class'} == 'Wall';
%             isWithinLevelTol = app.tableCompo_struct{:,"Size_Z"} > 11000;
%             selectedRow = isInClass & isWithinLevelTol;
%             app.tableCompo_struct{selectedRow, 'SpecSeqName1'} = categorical("load bearing wall");
%             
%             % also record the SpecSeqName in an array
%             SpecSeqName = categorical("load bearing wall");
%             SpecSeqName_class = categorical("Wall");
%             SpecSeqName_countNonUndefined = 0;
%             SpecSeqName_countNonUndefined = SpecSeqName_countNonUndefined + 1;
%             
            
            %{
            matrix compoLevelScenarios: each row records one scenario
            each 3-number series records:
                (the lower/upper end is) lessThan (the elevation of) levelToCompare, minusDeltaH
                lessThan:           1 for lessThan, -1 for largerThan, 0 if no such condition needed
                levelToCompare:     0 or 1
                minusDeltaH:        1 if the elevation should - deltaH, 0 if no need to - deltaH
            examples:
            scenario 2:
                (LOWER END) <, (lev+0)F, -deltaH,   ((no > condition)),   (UPPER END) <, (lev+0)F, -0,       >, (lev+0)F, -deltaH, 
                            1,    0,    1,           0,    0,    0,                   1,    0,    0,          -1,     0,     1
            scenario 4:
                (LOWER END) <, (lev+0)F, -0,      >, (lev+0)F, -deltaH,   (UPPER END) <, (lev+1)F, -deltaH,   >, (lev+1)F, -deltaH, 
                            1,    0,    0,          -1,    0,    1,                   1,    1,    1,          -1,     1,     1
            %}
%             compoLevelScenarios = [ ...
%                 1,  0,  1,    0,  0,  0,      1,  0,  1,    0,  0,  0;
%                 1,  0,  1,    0,  0,  0,      1,  0,  0,   -1,  0,  1;
%                 1,  0,  1,    0,  0,  0,      1,  1,  1,   -1,  0,  0;
%                 1,  0,  0,   -1,  0,  1,      1,  1,  1,   -1,  0,  0;
%                 1,  0,  1,    0,  0,  0,      1,  1,  0,   -1,  1,  1;
%                 1,  0,  0,   -1,  0,  1,      1,  1,  0,   -1,  1,  1;
%                 1,  0,  0,   -1,  0,  1,      0,  0,  0,   -1,  1,  0;
%                 1,  0,  1,    0,  0,  0,      0,  0,  0,   -1,  1,  0;
%                 1,  1,  0,   -1,  1,  1,      1,  1,  0,   -1,  1,  1];
            nF_allScenarios_bool = [app.nFButton.Value, app.nFButton_2.Value, app.nFButton_3.Value, app.nFButton_4.Value, ...
                app.nFButton_5.Value, app.nFButton_6.Value, app.nFButton_7.Value, app.nFButton_8.Value, app.nFButton_17.Value];
            n1F_allScenarios_bool = [app.n1FButton.Value, app.n1FButton_2.Value, app.n1FButton_3.Value, app.n1FButton_4.Value, ...
                app.n1FButton_5.Value, app.n1FButton_6.Value, app.n1FButton_7.Value, app.n1FButton_8.Value, app.n1FButton_17.Value];
            for scenario = 1:9
                app.tableCompo_struct = func_identifyCompoLevel(app, ...  % identify the base level of a compo
                        app.tableCompo_struct, app.floorElevation, deltaH_struct, app.nLevel, ...
                        app.compoLevelScenarios, scenario, nF_allScenarios_bool(scenario), n1F_allScenarios_bool(scenario));
            end
            % update compo list
            app.UITable_structCompo.Data = app.tableCompo_struct;
            
            
            % modify level for special case
            classToModify_S1 = app.Floor_S1_Thosecompo_structDropDown.Value;
            lowerEnd_lb_S1 = app.Floor_S1_andhas_structEditField.Value;
            lowerEnd_ub_S1 = app.Floor_S1_lowerend_structEditField.Value;
            upperEnd_lb_S1 = app.Floor_S1_and_structEditField.Value;
            upperEnd_ub_S1 = app.Floor_S1_upperend_structEditField.Value;
            levelToAssign_S1 = app.Floor_S1_areassignedto_structEditField.Value;
            [app.tableCompo_struct] = ...
                func_modifyTableCompoActivity_toModifyLevel(app, ... % function to modify level for special cases
                app.tableCompo_struct, ...
                classToModify_S1, lowerEnd_lb_S1, lowerEnd_ub_S1, ...
                upperEnd_lb_S1, upperEnd_ub_S1, levelToAssign_S1);

            
            % tableActivity_struct =======================================================
            app.tableActivity_struct = app.tableCompo_struct;
            
            % create a new table with new variables, before concat horizontally with old table
            varNames = ["AtomActName", repmat(" ", 1, app.nR*(1+3*app.nM))];
            varTypes = ["string", repmat(" ", 1, app.nR*(1+3*app.nM))];
            for res = 1:app.nR
                varNames(1 + (res-1)*(1+3*app.nM) + 1) = strcat("Resource_", num2str(res));
                varTypes(1 + (res-1)*(1+3*app.nM) + 1) = "string";
                for m = 1:app.nM
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 1) = strcat("Res", num2str(res), "Mode", num2str(m));
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 2) = strcat("Cost_r", num2str(res), "m", num2str(m));
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 3) = strcat("Eff_r", num2str(res), "m", num2str(m));
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 1) = "string";
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 2) = "double";
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 3) = "double";
                end
            end
            tableActivity_struct_toAdd = table('Size',[height(app.tableCompo_struct) length(varNames)], ...
                'VariableTypes',varTypes,'VariableNames',varNames);
            app.tableActivity_struct = [app.tableActivity_struct tableActivity_struct_toAdd];
            % move the location of the newly added columns to the left
            for i = 1:length(varNames)  
                app.tableActivity_struct = movevars(app.tableActivity_struct, varNames(i),'After',i);
            end
            
                         
            % Find the correct row in the database, by code, for each component
            tableActivity_struct_old = app.tableActivity_struct;  % to store as interim result
            %  ----------------------- NOTE!!!!!! -----------------------------------
            % THIS IS A BUILT-IN LIST
            % TO MAP STRUCTURAL CLASS, MATERIAL TYPE, AND THEIR CODE IN THE DATABASE FILE
            % FOR ONE'S OWN USE (I.E. WITH A DIFFERENT DATABASE), CHANGE THE CODES HERE
            %  ----------------------------------------------------------------------
            matList = categorical(["Staal", "Prefab beton", "Beton", "Hout", "Pile"]);
            matCode = ["staa", "betp", "beti", "hout", "pile"];
            classList = categorical(["Column", "Beam", "Slab", "Wall", "Roof", "Pile"]);
            classCode = ["1", "2", "3", "4", "3", "5"];
            % Map the activities with the database, using activity code
            app.tableActivity_struct = func_mapCompoAndDatabase(app.tableDatabase, tableActivity_struct_old, ...
                matList, matCode, classList, classCode, varNames);
            
            
            app.UITable_structAtomAct.ColumnName = app.tableActivity_struct.Properties.VariableNames;
            app.UITable_structAtomAct.Data = app.tableActivity_struct;
            app.UITable_structAtomAct.RowName = 'numbered';


        end

        % Button pushed function: 
        % Shownlistofatomactivities_mepButton
        function Shownlistofatomactivities_mepButtonPushed(app, event)
            % get elevation input
            app.floorElevation = str2double(app.H0toHnTextArea.Value);
            app.nLevel = length(app.floorElevation);
            app.nR = app.nREditField.Value;
            app.nM = app.nMEditField.Value;
            app.NumresourcetypesEditField_3.Value = string(app.nR);
            % show floor level in dropdown list in sequencing rules for whole building
            app.atlevelDropDown_S1_1.Items = string(0:1:app.nLevel-1)';
            app.atlevelDropDown_S1_2.Items = string(0:1:app.nLevel-1)';
                       
            % judge the level of all compo and special compo         
            deltaH_mep = app.DeltaH_mepEditField.Value;
            % Clear all the baselevel first
            app.tableCompo_mep{:, 'BaseLevel'} = repmat("", height(app.tableCompo_mep), 1);

            nF_allScenarios_bool = [app.nFButton_9.Value, app.nFButton_10.Value, app.nFButton_11.Value, app.nFButton_12.Value, ...
                app.nFButton_13.Value, app.nFButton_14.Value, app.nFButton_15.Value, app.nFButton_16.Value, app.nFButton_18.Value];
            n1F_allScenarios_bool = [app.n1FButton_9.Value, app.n1FButton_10.Value, app.n1FButton_11.Value, app.n1FButton_12.Value, ...
                app.n1FButton_13.Value, app.n1FButton_14.Value, app.n1FButton_15.Value, app.n1FButton_16.Value, app.n1FButton_18.Value];
            for scenario = 1:9
                app.tableCompo_mep = func_identifyCompoLevel(app, ...  % identify the base level of a compo
                        app.tableCompo_mep, app.floorElevation, deltaH_mep, app.nLevel, ...
                        app.compoLevelScenarios, scenario, nF_allScenarios_bool(scenario), n1F_allScenarios_bool(scenario));
            end
            % update compo list
            app.UITable_mepCompo.Data = app.tableCompo_mep;
            
            
            % tableActivity_mep =======================================================
            app.tableActivity_mep = app.tableCompo_mep;
            
            % create a new table with new variables, before concat horizontally with old table
            varNames = ["AtomActName", repmat(" ", 1, app.nR*(1+3*app.nM))];
            varTypes = ["string", repmat(" ", 1, app.nR*(1+3*app.nM))];
            for res = 1:app.nR
                varNames(1 + (res-1)*(1+3*app.nM) + 1) = strcat("Resource_", num2str(res));
                varTypes(1 + (res-1)*(1+3*app.nM) + 1) = "string";
                for m = 1:app.nM
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 1) = strcat("Res", num2str(res), "Mode", num2str(m));
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 2) = strcat("Cost_r", num2str(res), "m", num2str(m));
                    varNames(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 3) = strcat("Eff_r", num2str(res), "m", num2str(m));
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 1) = "string";
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 2) = "double";
                    varTypes(1 + (res-1)*(1+3*app.nM) + 1 + (m-1)*3 + 3) = "double";
                end
            end
            tableActivity_mep_toAdd = table('Size',[height(app.tableCompo_mep) length(varNames)], ...
                'VariableTypes',varTypes,'VariableNames',varNames);
            app.tableActivity_mep = [app.tableActivity_mep tableActivity_mep_toAdd];
            % move the location of the newly added columns to the left
            for i = 1:length(varNames)  
                app.tableActivity_mep = movevars(app.tableActivity_mep, varNames(i),'After',i);
            end
            
                         
            % Find the correct row in the database, by code, for each component
            tableActivity_mep_old = app.tableActivity_mep;  % to store as interim result
            %  ----------------------- NOTE!!!!!! -----------------------------------
            % THIS IS A BUILT-IN LIST
            % TO MAP STRUCTURAL CLASS, MATERIAL TYPE, AND THEIR CODE IN THE DATABASE FILE
            % FOR ONE'S OWN USE (I.E. WITH A DIFFERENT DATABASE), CHANGE THE CODES HERE
            %  ----------------------------------------------------------------------
            matList = categorical(["FlowSegmentMat", "FlowFittingMat"]);
            matCode = ["flow", "flow"];
            classList = categorical(["Flow segment", "Flow fitting"]);
            classCode = ["m1", "m1"];
            % Map the activities with the database, using activity code
            app.tableActivity_mep = func_mapCompoAndDatabase(app.tableDatabase, tableActivity_mep_old, ...
                matList, matCode, classList, classCode, varNames);
            
            
            app.UITable_mepAtomAct.ColumnName = app.tableActivity_mep.Properties.VariableNames;
            app.UITable_mepAtomAct.Data = app.tableActivity_mep;
            app.UITable_mepAtomAct.RowName = 'numbered';
            
            
        end

        % Button pushed function: 
        % EstimateoptimisationparametersButton
        function EstimateoptimisationparametersButtonPushed(app, event)
            % take input: zone dimension
            app.Zx_min = app.MinzonesizealongxEditField.Value;
            app.Zy_min = app.MinzonesizealongyEditField.Value;
            app.Zx_max = app.MaxzonesizealongxEditField.Value;
            app.Zy_max = app.MaxzonesizealongyEditField.Value;
            app.Zx_incre = app.IncrementofzonedimensionalongxEditField.Value;
            app.Zy_incre = app.IncrementofzonedimensionalongyEditField.Value;
            if app.Zx_incre == 0
                app.Zx = app.Zx_min;
            else
                app.Zx = app.Zx_min : app.Zx_incre : app.Zx_max;
            end
            if app.Zy_incre == 0
                app.Zy = app.Zy_min;
            else
                app.Zy = app.Zy_min : app.Zy_incre : app.Zy_max;
            end
            app.Zxy_record = repmat(app.Zy, [2, length(app.Zx)]);
            % Zxy_record:   zx1  zx1  zx1  zx2  zx2  zx2
            %               zy1  zy2  zy3  zy1  zy2  zy3
            for i = 1:length(app.Zx)
                app.Zxy_record(1, (i-1)*length(app.Zy)+1 : i*length(app.Zy)) = repmat(app.Zx(i), [1, length(app.Zy)]);
            end
            % take input: sequence
            app.Dmin_h = app.D_minhEditField.Value;
            app.Dmin_v = app.D_minvEditField.Value;
            app.Dwallmin = app.D_minWallEditField.Value;
            app.Dceilmin = app.D_minCeilEditField.Value;
            app.Dmin_h2 = app.D_minh2EditField.Value;
            app.Dmin_v2 = app.D_minv2EditField.Value;
            app.D_length_long = app.L_longEditField.Value;
            app.D_length_short = app.L_shortEditField.Value;
            app.D_crosssec_large = app.A_largeEditField.Value;
            app.D_crosssec_small = app.A_smallEditField.Value;
            app.timeUnitConver = str2double(cellstr(app.TimeunitoftheproblemDropDown.Value));
            % take input: classNonEssential_struct
            app.classEssential_struct = categorical([string(app.Seq1DropDown.Value), string(app.Seq2DropDown.Value), ...
                string(app.Seq3DropDown.Value), string(app.Seq4DropDown.Value), string(app.Seq5DropDown.Value),]);
            app.classNonEssential_struct = app.classEssential_struct;
            classEssentialCheckBox_struct = [app.CheckBox.Value, app.CheckBox_2.Value, app.CheckBox_3.Value, ...
                app.CheckBox_4.Value, app.CheckBox_5.Value];
            for i = 5:-1:1  % delete the non-essential or essential classes from the lists
                if classEssentialCheckBox_struct(i) == false
                    app.classEssential_struct(i) = [];
                else
                    app.classNonEssential_struct(i) = [];
                end
            end
            app.classSeq_struct = [app.classEssential_struct, app.classNonEssential_struct];
            % take input: quantity of resources
            app.Qrdcoeff_struct = app.Numres1Spinner.Value;
            app.Qrdcoeff_mep = app.Numres2Spinner.Value;
            % take input: optimisation options
            app.optMaxTime = app.MaxtimeperruninsEditField.Value;
            app.onlyX0_bool = app.Generateinitialsolutiononlywithw21CheckBox.Value;     
            
            % coeffCost, coeffDura, a priori or a posteriori
            if app.IfaprioriButton.Value == true
                app.coeffCost = app.Weightofobj1w1EditField.Value;
            else
                app.coeffCost = 1/(app.NumsetsEditField.Value+1): 1/(app.NumsetsEditField.Value+1): 1-1/(app.NumsetsEditField.Value+1);
            end
            app.coeffDura = 1 - app.coeffCost;
            app.normalisationFactor_objCost = app.Factorforobj1EditField.Value;
            app.normalisationFactor_objDura = app.Factorforobj2EditField.Value;
            
            app.options = optimoptions('intlinprog','MaxTime', app.optMaxTime, ...
                'Heuristics', 'advanced', 'ConstraintTolerance', 1e-3, ...
                'CutGeneration', 'intermediate', 'IntegerTolerance', 1e-3);
            % result_record:    zx1  zx1  zx1  zx2  zx2  zx2
            %                   zy1  zy2  zy3  zy1  zy2  zy3
            %                   w1   w1   w1   w1   w1   w1
            %                   w2   w2   w2   w2   w2   w2
            %                   cost cost cost ...
            %                   time time time ...
            if app.Zx_incre == 0 && app.Zy_incre == 0
                app.result_record = zeros(6, app.NumsetsEditField.Value);
                app.result_record(1:2, :) = repmat([app.Zx_min; app.Zy_min], [1, app.NumsetsEditField.Value]);
                app.result_record(3, :) = app.coeffCost;
                app.result_record(4, :) = app.coeffDura;
            else 
                app.result_record = zeros(6, size(app.Zxy_record, 2));
                app.result_record(1:2, :) = app.Zxy_record;
                app.result_record(3:4, :) = repmat([app.coeffCost; app.coeffDura], [1, app.Zxy_record]);
            end


            % check from which corner the construction starts
            if categorical(cellstr(app.ConstructionstartingfromwhichcornerDropDown.Value)) == categorical("bottom-left")  % char to cell to categorical
                app.bottom2top_bool = 1; app.left2right_bool = 1; 
            end
            if categorical(cellstr(app.thenalongwhichaxisfirstDropDown.Value)) == categorical("y")  % char to cell to categorical
                app.verticleDirFirst_bool = 1;
            end
            
            % update the fields showing optimisation parameters
            app.MaxnumzonesalongxEditField.Value = ceil((app.building_x_max - app.building_x_min)/app.Zx_min);
            app.MaxnumzonesalongyEditField.Value = ceil((app.building_y_max - app.building_y_min)/app.Zy_min);
            app.NumfloorsEditField_2.Value = app.nLevel;
            app.NumactmodesEditField.Value = app.nM;
            app.NumresourcetypesEditField_2.Value = app.nR;
            
            
            %% do basic operation / checking
            % =================================================================================
            % Struct: create tableZoned, for the largest nZ (for checking) ===========================
            % record special seq in tableCompo_struct and tableActivity_struct
            app.classToSeq_S1_1 = categorical(cellstr(app.classDropDown_S1_1.Value));
            app.classToSeq_S1_2 = categorical(cellstr(app.classDropDown_S1_2.Value));
            app.levelToSeq_S1_1 = str2double(cellstr(app.atlevelDropDown_S1_1.Value));
            app.levelToSeq_S1_2 = str2double(cellstr(app.atlevelDropDown_S1_2.Value));
            app.beforeAfter_bool = 0;
            if categorical(cellstr(app.beforeafterDropDown_S1_1.Value)) == categorical("before") 
                app.beforeAfter_bool = 1;
            end
            lowerEnd_lb_S1 = app.andhas_structEditField_S1_1.Value;
            lowerEnd_ub_S1 = app.lowerend_structEditField_S1_1.Value;
            upperEnd_lb_S1 = app.and_structEditField_S1_1.Value;
            upperEnd_ub_S1 = app.upperend_structEditField_S1_1.Value;
            [app.tableCompo_struct, app.SpecSeqName, app.SpecSeqName_class, app.SpecSeqName_countNonUndefined, ...
                app.tableActivity_struct] ...
               = func_findSpecSeq_struct(app.tableCompo_struct, app.tableActivity_struct, app.nLevel, app.floorElevation, ...
               app.classToSeq_S1_1, lowerEnd_lb_S1, lowerEnd_ub_S1, ...
               upperEnd_lb_S1, upperEnd_ub_S1);
            
            % create tableZoned_struct
            [app.tableZoned_struct, app.tableActivity_struct, app.nA_struct, app.nZ] = ...
                func_createTableZoned_struct(app.tableActivity_struct, app.tableCompo_struct, ...
                app.building_x_max, app.building_x_min, app.building_y_max, app.building_y_min, ...
                app.Zx_min, app.Zy_min, app.nLevel, app.nAllEleGuid, app.nR, app.nM, ...
                app.SpecSeqName, app.SpecSeqName_class, app.SpecSeqName_countNonUndefined, ...
                app.bottom2top_bool, app.left2right_bool, app.verticleDirFirst_bool);
            % display in UI
            app.UITable_tableZoned_struct.ColumnName = app.tableZoned_struct.Properties.VariableNames;
            app.UITable_tableZoned_struct.Data = app.tableZoned_struct;
            app.UITable_tableZoned_struct.RowName = 'numbered';
            % update compo and activity list
            app.UITable_structCompo.Data = app.tableCompo_struct;
            app.UITable_structAtomAct.ColumnName = app.tableActivity_struct.Properties.VariableNames;
            app.UITable_structAtomAct.Data = app.tableActivity_struct;
            app.UITable_structAtomAct.RowName = 'numbered';
            % print unique class names
            func_updateCompoClassNameInAllTextArea_struct(app, ... % function to update class names in all text area
                app.tableCompo_struct);
            % find dura and cost of struct activity clusters
            [duration_struct, cost_struct, app.nD_struct, app.nvars_struct] = func_structSeq_findDuraCost(app.tableZoned_struct, ...
                app.nA_struct, app.nR, app.nM, app.timeUnitConver);
            % find lists of pred and succ (struct)
            [seq_pred_struct, seq_succ_struct, buffer_struct] = func_structSeq_findPredSuccLists( ...
                app.tableZoned_struct, ...
                app.classEssential_struct, app.classNonEssential_struct, app.classSeq_struct, ...
                app.nZ, app.SpecSeqName, app.SpecSeqName_countNonUndefined, ...
                app.classToSeq_S1_2, app.beforeAfter_bool, app.levelToSeq_S1_1, app.levelToSeq_S1_2);
            
            
            % =================================================================================
            % MEP: create tableZoned, for the largest nZ (for checking) ===========================
            % MEP: initialise tableZonedMEP
            [app.tableZoned_mep, app.tableActivity_mep, app.nZ, nClass_mep, compoClass_mep] ...
                = func_createTableZoned_mep_empty ...
                (app.tableActivity_mep, app.building_x_max, app.building_x_min, app.building_y_max, app.building_y_min, ...
                app.Zx_min, app.Zy_min, app.nLevel, app.nR, app.nM, app.bottom2top_bool, app.left2right_bool, app.verticleDirFirst_bool); 
            % MEP: the whole clustering & splitting -------------------
            [M_SSR, M1_seq, M_coordDiff, check1, check2, check3, ...
                M_SIZE, M2_seq, M_length, M_section, M_seq, app.tableCompo_mep2, recordCompoInClusterIdx, tableZoned_mep2, ...
                M_clusterSeq_befSplit, tableAllCompoClusterBeforeSplit, ...
                seq_pred_mep_constSeq, seq_succ_mep_constSeq, seq_pred_mep_SR, seq_succ_mep_SR, buffer_mep_SR, ...
                clusterName_new, clusterSplitRecord, tableAllCompo_whenSplitting, M_clusterSeq_whenSplitting, ...
                clusterSplitRecord_befS2, tableCompo_mep2_befS2, seq_pred_mep_constSeq_befS2, seq_succ_mep_constSeq_befS2, ...
                seq_pred_mep_SR_befS2, seq_succ_mep_SR_befS2, toCreateNewSub, app.tableZoned_mep_splitted, clusterName_new_new] ...
                = func_mepSeq ...
                (app.tableCompo_mep, app.tableCompo_struct, app.Dmin_h, app.Dmin_v, app.Dwallmin, app.Dceilmin, ...
                app.Dmin_h2, app.Dmin_v2, app.D_length_long, app.D_length_short, app.D_crosssec_large, app.D_crosssec_small, ...
                app.tableActivity_mep, nClass_mep, app.nLevel, app.nZ, app.tableZoned_mep, compoClass_mep, app.nR, app.nM, app.nAllEleGuid);
            % display in UI
            app.UITable_tableZoned_mep.ColumnName = app.tableZoned_mep_splitted.Properties.VariableNames;
            app.UITable_tableZoned_mep.Data = app.tableZoned_mep_splitted;
            app.UITable_tableZoned_mep.RowName = 'numbered';
            % update compo list
            app.UITable_mepCompo.Data = app.tableCompo_mep2;
%             app.ResultofcheckingsuggestionsTextArea.Value = string([clusterSplitRecord_befS2(:,2)', ...
%                 sum(M_seq>0, 'all'), sum(M1_seq>0, 'all'), sum(M2_seq>0, 'all'), ...
%                 sum(M_SSR>0, 'all'), sum(M_length>0, 'all'), sum(M_section>0, 'all'), sum(M_coordDiff>0, 'all'), ...
%                 sum(M_clusterSeq_befSplit>0, 'all')]);       
            % find dura and cost of mep activity clusters
            [seq_pred_mep, seq_succ_mep, buffer_mep, app.nD_mep, app.nvars_mep, duration_mep, cost_mep, app.nA_mep] = ...
                func_mepSeq_findDuraCost ...
                (app.tableZoned_mep_splitted, ...
                app.nR, app.nM, app.timeUnitConver, ...
                seq_pred_mep_constSeq, seq_succ_mep_constSeq, seq_pred_mep_SR, seq_succ_mep_SR);
            
            
            % ==== struct & MEP =============================================================================
            %% Find precedence between struct and MEP systems
            % And combine tableZoned_struct and tableZoned_mep_splitted, and prep the inputs for "solve" function
            [seq_pred_structmep, seq_succ_structmep, buffer_structmep, nvars_extraForx0, ...
                duration, cost, seq_pred, seq_succ, buffer, app.nA, nD, UR_ar, URp_ar, nvars, Qrd] = ...
                func_combineStructAndMep...
                (app.tableZoned_struct, app.tableZoned_mep_splitted, app.classEssential_struct, app.nR, app.nM, ...
                duration_struct, duration_mep, cost_struct, cost_mep, buffer_struct, buffer_mep, app.nA_struct, app.nA_mep, ...
                seq_pred_struct, seq_pred_mep, seq_succ_struct, seq_succ_mep, app.Qrdcoeff_struct, app.Qrdcoeff_mep);
            
            % Update optimisation parameters
            app.NumactivitiesEditField.Value = app.nA;
            app.NumactmodesEditField.Value = app.nM;
            app.NumresourcetypesEditField_2.Value = app.nR;
            app.NumdaysestEditField.Value = nD /8 * app.timeUnitConver;
            app.NumtimeunitsestEditField.Value = nD;
            app.NumrunsofoptimisationEditField.Value = size(app.result_record, 2);
            app.NumvariablesnvarsSuggestedtobe6000EditField.Value = string(nvars);
            app.MaxtimecompletingallrunsinminEditField.Value = string(size(app.result_record, 2) * app.optMaxTime / 60);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            for count = 1:size(app.result_record, 2)  % loop 
                % the changing problem settings
                app.Zx = app.result_record(1, count);
                app.Zy = app.result_record(2, count);
                app.coeffCost = app.result_record(3, count);
                app.coeffDura = app.result_record(4, count);
                    
                
                % =================================================================================
                % create tableZoned_struct
                [app.tableZoned_struct, app.tableActivity_struct, app.nA_struct, app.nZ] = ...
                    func_createTableZoned_struct(app.tableActivity_struct, app.tableCompo_struct, ...
                    app.building_x_max, app.building_x_min, app.building_y_max, app.building_y_min, ...
                    app.Zx, app.Zy, app.nLevel, app.nAllEleGuid, app.nR, app.nM, ...
                    app.SpecSeqName, app.SpecSeqName_class, app.SpecSeqName_countNonUndefined, ...
                    app.bottom2top_bool, app.left2right_bool, app.verticleDirFirst_bool);
                % find dura and cost of struct activity clusters
                [duration_struct, cost_struct, app.nD_struct, app.nvars_struct] = func_structSeq_findDuraCost(app.tableZoned_struct, ...
                    app.nA_struct, app.nR, app.nM, app.timeUnitConver);
                % find lists of pred and succ (struct)
                [seq_pred_struct, seq_succ_struct, buffer_struct] = func_structSeq_findPredSuccLists( ...
                    app.tableZoned_struct, ...
                    app.classEssential_struct, app.classNonEssential_struct, app.classSeq_struct, ...
                    app.nZ, app.SpecSeqName, app.SpecSeqName_countNonUndefined, ...
                    app.classToSeq_S1_2, app.beforeAfter_bool, app.levelToSeq_S1_1, app.levelToSeq_S1_2);
                
                
                % =================================================================================
                % MEP: create tableZoned, for the largest nZ (for checking) ===========================
                % MEP: initialise tableZonedMEP
                [app.tableZoned_mep, app.tableActivity_mep, app.nZ, nClass_mep, compoClass_mep] ...
                    = func_createTableZoned_mep_empty ...
                    (app.tableActivity_mep, app.building_x_max, app.building_x_min, app.building_y_max, app.building_y_min, ...
                    app.Zx, app.Zy, app.nLevel, app.nR, app.nM, app.bottom2top_bool, app.left2right_bool, app.verticleDirFirst_bool); 
                % MEP: the whole clustering & splitting -------------------
                [M_SSR, M1_seq, M_coordDiff, check1, check2, check3, ...
                    M_SIZE, M2_seq, M_length, M_section, M_seq, app.tableCompo_mep2, recordCompoInClusterIdx, tableZoned_mep2, ...
                    M_clusterSeq_befSplit, tableAllCompoClusterBeforeSplit, ...
                    seq_pred_mep_constSeq, seq_succ_mep_constSeq, seq_pred_mep_SR, seq_succ_mep_SR, buffer_mep_SR, ...
                    clusterName_new, clusterSplitRecord, tableAllCompo_whenSplitting, M_clusterSeq_whenSplitting, ...
                    clusterSplitRecord_befS2, tableCompo_mep2_befS2, seq_pred_mep_constSeq_befS2, seq_succ_mep_constSeq_befS2, ...
                    seq_pred_mep_SR_befS2, seq_succ_mep_SR_befS2, toCreateNewSub, app.tableZoned_mep_splitted, clusterName_new_new] ...
                    = func_mepSeq ...
                    (app.tableCompo_mep, app.tableCompo_struct, app.Dmin_h, app.Dmin_v, app.Dwallmin, app.Dceilmin, ...
                    app.Dmin_h2, app.Dmin_v2, app.D_length_long, app.D_length_short, app.D_crosssec_large, app.D_crosssec_small, ...
                    app.tableActivity_mep, nClass_mep, app.nLevel, app.nZ, app.tableZoned_mep, compoClass_mep, app.nR, app.nM, app.nAllEleGuid);     
                % find dura and cost of mep activity clusters
                [seq_pred_mep, seq_succ_mep, buffer_mep, app.nD_mep, app.nvars_mep, duration_mep, cost_mep, app.nA_mep] = ...
                    func_mepSeq_findDuraCost ...
                    (app.tableZoned_mep_splitted, ...
                    app.nR, app.nM, app.timeUnitConver, ...
                    seq_pred_mep_constSeq, seq_succ_mep_constSeq, seq_pred_mep_SR, seq_succ_mep_SR);
                
                
                % ==== struct & MEP =============================================================================
                %% Find precedence between struct and MEP systems
                % And combine tableZoned_struct and tableZoned_mep_splitted, and prep the inputs for "solve" function
                [seq_pred_structmep, seq_succ_structmep, buffer_structmep, nvars_extraForx0, ...
                    duration, cost, app.seq_pred, app.seq_succ, buffer, app.nA, nD, UR_ar, URp_ar, nvars, Qrd] = ...
                    func_combineStructAndMep...
                    (app.tableZoned_struct, app.tableZoned_mep_splitted, app.classEssential_struct, app.nR, app.nM, ...
                    duration_struct, duration_mep, cost_struct, cost_mep, buffer_struct, buffer_mep, app.nA_struct, app.nA_mep, ...
                    seq_pred_struct, seq_pred_mep, seq_succ_struct, seq_succ_mep, app.Qrdcoeff_struct, app.Qrdcoeff_mep);
                
                %% Generate initial solution(s)
                [seq_pred_x0, I] = sort(app.seq_pred);
                seq_succ_x0 = app.seq_succ(I);
                [x0, x0_reshaped_after_orig, ESa, EFa, ESar, EFar, Uard_sumA] = func_generateInitialSolution_v4_12283(duration, cost, app.nA, nD, app.nR, app.nM, nvars, ...
                    Qrd, seq_pred_x0, seq_succ_x0, buffer, UR_ar, URp_ar, app.options);
                %app.ResultofcheckingsuggestionsTextArea.Value = string([app.timeUnitConver; EFa]);
                % Adjust the inputs for opt problem, between generation of x0 and the actual run
                [nD, nvars, Qrd, n1Res, n1Act, x0_reshaped_after, x0] = func_adjustBeforeOpt...
                    (duration, app.nA, app.nR, app.nM, app.Qrdcoeff_struct, app.Qrdcoeff_mep, nvars_extraForx0, x0);


                % Run optimisation
                [app.sol,x,fval,exitflag,output, obj1_value_dura, obj2_value_cost] = func_solve_intlinprog_2obj... % (here obj1: dura; obj2: cost)
                    (x0, duration, cost, app.nA, nD, app.nR, app.nM, nvars, ...
                    Qrd, app.seq_pred, app.seq_succ, buffer, app.options, ...
                    app.coeffDura, app.coeffCost, app.onlyX0_bool, app.normalisationFactor_objDura, app.normalisationFactor_objCost);
                
                % record the result
                app.result_record(5, count) = obj2_value_cost;
                app.result_record(6, count) = obj1_value_dura;
                                
                % display result in table, if a posteriori 
                table_aPosteriori = array2table(app.result_record, 'RowNames', {'Zx','Zy','coeffCost','coeffDura','Cost','Dura'});
                % app.UITable_resultIfAPosteriori.ColumnName = app.table_aPosteriori.Properties.VariableNames;
                app.UITable_resultIfAPosteriori.Data = table_aPosteriori;
                app.UITable_resultIfAPosteriori.RowName = table_aPosteriori.Properties.RowNames;

                
%             % find sequence: struct
%             app.classEssential_struct = categorical([string(app.Seq1DropDown.Value), string(app.Seq2DropDown.Value), ...
%                 string(app.Seq3DropDown.Value), string(app.Seq4DropDown.Value), string(app.Seq5DropDown.Value),]);
%             app.classNonEssential_struct = app.classEssential_struct;
%             classEssentialCheckBox_struct = [app.CheckBox.Value, app.CheckBox_2.Value, app.CheckBox_3.Value, ...
%                 app.CheckBox_4.Value, app.CheckBox_5.Value];
%             for i = 5:-1:1  % delete the non-essential or essential classes from the lists
%                 if classEssentialCheckBox_struct(i) == false
%                     app.classEssential_struct(i) = [];
%                 else
%                     app.classNonEssential_struct(i) = [];
%                 end
%             end
%             classSeq_struct = [app.classEssential_struct, app.classNonEssential_struct];
%             app.ResultofcheckingsuggestionsTextArea.Value = [string(app.bottom2top_bool), string(app.left2right_bool), string(app.verticleDirFirst_bool)];  % checking
%             app.ResultofcheckingsuggestionsTextArea.Value = string([seq_pred_struct, seq_succ_struct]);  % checking
            end
            
            % display result in table, if a priori
            app.UITable_resultIfAPriori.ColumnName = app.sol.Properties.VariableNames;
            app.UITable_resultIfAPriori.Data = app.sol;
            app.UITable_resultIfAPriori.RowName = app.sol.Properties.RowNames;

        end

        % Button pushed function: ExportasXlsxFileBtn
        function ExportasXlsxFileBtnButtonPushed(app, event)
            pjStart = app.ProjectstartingdateDatePicker.Value;
            NumberHolidays = 0;
            resultFileDir = app.ExportScheduleDirectoryEditField.Value;
            [sol_re, sol_re2] = func_writeSol...
                (app.sol, pjStart, NumberHolidays, app.nR, app.nA, app.tableZoned_struct, ...
                app.tableZoned_mep_splitted, app.seq_pred, app.seq_succ, app.nAllEleGuid);
            
            % write the schedule result in to Excel
            writetable(app.sol, resultFileDir,'Sheet',1,'Range','A1','WriteRowNames',true);
            writetable(sol_re, resultFileDir,'Sheet',2,'Range','A1','WriteRowNames',true);      
            writetable(sol_re2, resultFileDir,'Sheet',3,'Range','A1','WriteRowNames',true); 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Scheduler = uifigure('Visible', 'off');
            app.Scheduler.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.Scheduler);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.StartText2 = uilabel(app.StartTab);
            app.StartText2.Text = {'This is part 2 of a software tool developed in a MSc graduation project in Delft University of Technology. The project aims to investigate the optimisation of construction schedules for building structural and MEP systems considering working zones.'; ''; 'This MATLAB programme reads component information from 3D building model(s) in .xlsx, takes in user preferences, and generate (optimised) schedule(s) for the building.'};
            app.LinktodetaileddocumentationxxxLabel_3 = uilabel(app.StartTab);
            app.LinktodetaileddocumentationxxxLabel_3.Text = {'Link to the thesis report in TU Delft Repository: '; 'http://resolver.tudelft.nl/uuid:eeed7784-9632-4ecb-932e-74f49d5aea99'; ''; 'It is highly recommended to read the documentation before using this tool.'; ''; 'Link to download and documentation:'; 'https://github.com/XinzhiJiang123/construction-schedule-optimisation'; ''; 'Last updated: 17 Dec 2022'; 'License: Apache License 2.0'};
            app.StepsLabel_3 = uilabel(app.StartTab);
            app.StepsLabel_3.Text = {'*  Integrated schedule for structural and MEP systems'; ''; '*  Customisable sequencing rules'; ''; '*  Insights into tproject time-cost rade-off'; ''; '*  Automatic schedule generation and schedule optimisation'; ''; '*  Component-level activities clustered based on working zones'};
            app.FeaturesLabel = uilabel(app.StartTab);
            app.FeaturesLabel.Text = 'Features';
            app.ConstructionScheduleOptimisationLabel = uilabel(app.StartTab);
            app.ConstructionScheduleOptimisationLabel.Text = 'Construction schedule optimisation: software implementation (part 2)';
            app.Image_softwareArchi = uiimage(app.StartTab);
            app.Image_softwareArchi.ImageSource = 'Overview_marked.PNG';
            app.IntroductionLabel = uilabel(app.StartTab);
            app.IntroductionLabel.Text = 'Introduction';
            app.LinksLabel = uilabel(app.StartTab);
            app.LinksLabel.Text = 'Links';
            app.TabData1 = uitab(app.TabGroup);
            app.TabData1.Title = '1. Input files';
            app.TabGroup4 = uitabgroup(app.TabData1);
            app.StructuralcompoTab = uitab(app.TabGroup4);
            app.StructuralcompoTab.Title = 'Structural compo';
            app.ReadfileButton_structCompo = uibutton(app.StructuralcompoTab, 'push');
            app.ReadfileButton_structCompo.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButton_structCompoPushed, true);
            app.ReadfileButton_structCompo.Text = 'Read file';
            app.ReadstructuralcomponentlistLabel = uilabel(app.StructuralcompoTab);
            app.ReadstructuralcomponentlistLabel.Text = 'Read structural component list';
            app.UITable_structCompo = uitable(app.StructuralcompoTab);
            app.UITable_structCompo.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_structCompo.RowName = {};
            app.DirectoryEditFieldLabel = uilabel(app.StructuralcompoTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Directory_structCompoEditField = uieditfield(app.StructuralcompoTab, 'text');
            app.Directory_structCompoEditField.Value = 'E:\THESIS\software\Case\Sample case_structure.xlsx';
            app.FurtherprocessthestructuralcompolistLabel = uilabel(app.StructuralcompoTab);
            app.FurtherprocessthestructuralcompolistLabel.Text = 'Further process the structural compo list:';
            app.TabGroup5 = uitabgroup(app.StructuralcompoTab);
            app.IfapplicableChangeunitTab = uitab(app.TabGroup5);
            app.IfapplicableChangeunitTab.Title = '(If applicable) Change unit';
            app.Updatecompolist_struct_changeUnitButton = uibutton(app.IfapplicableChangeunitTab, 'push');
            app.Updatecompolist_struct_changeUnitButton.ButtonPushedFcn = createCallbackFcn(app, @Updatecompolist_struct_changeUnitButtonPushed, true);
            app.Updatecompolist_struct_changeUnitButton.Text = 'Update compo list';
            app.NeedtoconvertfrommtommSwitchLabel = uilabel(app.IfapplicableChangeunitTab);
            app.NeedtoconvertfrommtommSwitchLabel.Text = 'Need to convert from m to mm?';
            app.Needtoconvertfrommtomm_structSwitch = uiswitch(app.IfapplicableChangeunitTab, 'slider');
            app.Needtoconvertfrommtomm_structSwitch.Items = {'N', 'Y'};
            app.Needtoconvertfrommtomm_structSwitch.Value = 'Y';
            app.TochangeunitofsomecolumnsegfrommtommLabel_2 = uilabel(app.IfapplicableChangeunitTab);
            app.TochangeunitofsomecolumnsegfrommtommLabel_2.Text = {'The unit of all length-related values should be mm, e.g. values of coordinates or size.'; 'In case some values are in m, should change the unit from m to mm.'};
            app.ConverttheunitsofthesevaluesfrommtommListBoxLabel = uilabel(app.IfapplicableChangeunitTab);
            app.ConverttheunitsofthesevaluesfrommtommListBoxLabel.Text = 'Convert the units of these values from m to mm:';
            app.Converttheunitsofthesevalues_structListBox = uilistbox(app.IfapplicableChangeunitTab);
            app.Converttheunitsofthesevalues_structListBox.Items = {'CentroidX', 'CentroidY', 'CentroidZ', 'Size_X', 'Size_Y', 'Size_Z'};
            app.Converttheunitsofthesevalues_structListBox.Multiselect = 'on';
            app.Converttheunitsofthesevalues_structListBox.Value = {'CentroidX', 'CentroidY', 'CentroidZ', 'Size_X', 'Size_Y', 'Size_Z'};
            app.clickonlyonceLabel = uilabel(app.IfapplicableChangeunitTab);
            app.clickonlyonceLabel.Text = '(click only once!)';
            app.KeepdiscardcompoTab = uitab(app.TabGroup5);
            app.KeepdiscardcompoTab.Title = 'Keep/discard compo';
            app.thosecompowiththefollowingwordsTextAreaLabel = uilabel(app.KeepdiscardcompoTab);
            app.thosecompowiththefollowingwordsTextAreaLabel.Text = 'those compo with the following words ';
            app.Tokeepdiscard_thosecompowith_struct_rule1TextArea = uitextarea(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_thosecompowith_struct_rule1TextArea.Value = {'Drukpaal'; 'Funderingsbalk'; 'Betonaanstort'; 'Kolom'; 'Ligger'; 'Decke'; 'Wand'; 'Dach'};
            app.ToLabel = uilabel(app.KeepdiscardcompoTab);
            app.ToLabel.Text = 'To';
            app.Tokeepdiscard_struct_rule1ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_struct_rule1ListBox.Items = {'keep', 'discard'};
            app.Tokeepdiscard_struct_rule1ListBox.Value = 'keep';
            app.intheirListBox_4Label = uilabel(app.KeepdiscardcompoTab);
            app.intheirListBox_4Label.Text = 'in their';
            app.Tokeepdiscard_intheir_struct_rule1ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_intheir_struct_rule1ListBox.Items = {'name', 'class'};
            app.Tokeepdiscard_intheir_struct_rule1ListBox.ItemsData = {'Name', 'Class'};
            app.Tokeepdiscard_intheir_struct_rule1ListBox.Value = 'Name';
            app.thosecompowiththefollowingwordsTextArea_2Label = uilabel(app.KeepdiscardcompoTab);
            app.thosecompowiththefollowingwordsTextArea_2Label.Text = 'those compo with the following words ';
            app.Tokeepdiscard_thosecompowith_struct_rule2TextArea = uitextarea(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_thosecompowith_struct_rule2TextArea.Value = {'FoundationBeam'; 'Betonaanstort'; 'kanaalplaat'};
            app.ToLabel_2 = uilabel(app.KeepdiscardcompoTab);
            app.ToLabel_2.Text = 'To';
            app.Tokeepdiscard_struct_rule2ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_struct_rule2ListBox.Items = {'keep', 'discard'};
            app.Tokeepdiscard_struct_rule2ListBox.Value = 'discard';
            app.intheirListBox_5Label = uilabel(app.KeepdiscardcompoTab);
            app.intheirListBox_5Label.Text = 'in their';
            app.Tokeepdiscard_intheir_struct_rule2ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_intheir_struct_rule2ListBox.Items = {'name', 'class'};
            app.Tokeepdiscard_intheir_struct_rule2ListBox.ItemsData = {'Name', 'Class'};
            app.Tokeepdiscard_intheir_struct_rule2ListBox.Value = 'Class';
            app.ignorecaseListBoxLabel = uilabel(app.KeepdiscardcompoTab);
            app.ignorecaseListBoxLabel.Text = 'ignore case?';
            app.Tokeepdiscard_ignorecase_struct_rule1ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_ignorecase_struct_rule1ListBox.Items = {'Y', 'N'};
            app.Tokeepdiscard_ignorecase_struct_rule1ListBox.Value = 'Y';
            app.ignorecaseListBox_2Label = uilabel(app.KeepdiscardcompoTab);
            app.ignorecaseListBox_2Label.Text = 'ignore case?';
            app.Tokeepdiscard_ignorecase_struct_rule2ListBox = uilistbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_ignorecase_struct_rule2ListBox.Items = {'Y', 'N'};
            app.Tokeepdiscard_ignorecase_struct_rule2ListBox.Value = 'Y';
            app.TokeepdiscardcompoLabel_3 = uilabel(app.KeepdiscardcompoTab);
            app.TokeepdiscardcompoLabel_3.Text = {'Not all compo are needed in schedule.'; 'Recommend to keep only the main structural compo, and discard others (e.g. small connections), using one or more filtering rules:'};
            app.Tokeepdiscard_Applyfilteringrule1_structCheckBox = uicheckbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_Applyfilteringrule1_structCheckBox.Text = 'Apply filtering rule 1:';
            app.Tokeepdiscard_Applyfilteringrule1_structCheckBox.Value = true;
            app.Tokeepdiscard_Applyfilteringrule2_structCheckBox = uicheckbox(app.KeepdiscardcompoTab);
            app.Tokeepdiscard_Applyfilteringrule2_structCheckBox.Text = 'Apply filtering rule 2:';
            app.Tokeepdiscard_Applyfilteringrule2_structCheckBox.Value = true;
            app.Tokeepdiscard_Updatecompolist_structButton = uibutton(app.KeepdiscardcompoTab, 'push');
            app.Tokeepdiscard_Updatecompolist_structButton.ButtonPushedFcn = createCallbackFcn(app, @Tokeepdiscard_Updatecompolist_structButtonPushed, true);
            app.Tokeepdiscard_Updatecompolist_structButton.Text = 'Update compo list ';
            app.UpdateclassnamesTab = uitab(app.TabGroup5);
            app.UpdateclassnamesTab.Title = 'Update class names';
            app.UpdateclassnamesLabel = uilabel(app.UpdateclassnamesTab);
            app.UpdateclassnamesLabel.Text = {'This tool recognises the following class names:'; '            Pile, FoundationBeam, Betonaanstort, kanaalplaat, Column, Beam, Slab, Wall, Roof.'; 'Recognisable names should be used to correctly map compo with the database.'; ''; 'This section replaces current class names by recognisable ones.'};
            app.ForcompowiththesewordsTextAreaLabel = uilabel(app.UpdateclassnamesTab);
            app.ForcompowiththesewordsTextAreaLabel.Text = 'For compo with these words';
            app.ToUpdateClass_Forcompowith_structTextArea = uitextarea(app.UpdateclassnamesTab);
            app.ToUpdateClass_Forcompowith_structTextArea.Value = {'Funderingsbalk'; 'Betonaanstort'; 'kanaalplaat'; 'Kolom'; 'Ligger'; 'Decke'; 'Wand'; 'Dach'; 'Drukpaal'};
            app.writerecognisableclassnameinsequenceLabel = uilabel(app.UpdateclassnamesTab);
            app.writerecognisableclassnameinsequenceLabel.Text = ', write recognisable class name (in sequence) ';
            app.ToUpdateClass_writerecognisable_structTextArea = uitextarea(app.UpdateclassnamesTab);
            app.ToUpdateClass_writerecognisable_structTextArea.Value = {'FoundationBeam'; 'Betonaanstort'; 'kanaalplaat'; 'Column'; 'Beam'; 'Slab'; 'Wall'; 'Roof'; 'Pile'};
            app.ToUpdateClass_Updatecompolist_structButton = uibutton(app.UpdateclassnamesTab, 'push');
            app.ToUpdateClass_Updatecompolist_structButton.ButtonPushedFcn = createCallbackFcn(app, @ToUpdateClass_Updatecompolist_structButtonPushed, true);
            app.ToUpdateClass_Updatecompolist_structButton.Text = 'Update compo list ';
            app.CurrentclassnamesPanel = uipanel(app.UpdateclassnamesTab);
            app.CurrentclassnamesPanel.Title = 'Current class names:';
            app.Currentclassnames_structTextArea = uitextarea(app.CurrentclassnamesPanel);
            app.Currentclassnames_structTextArea.Editable = 'off';
            app.Currentclassnames_structTextArea.Value = {'Drukpaal'; 'Funderingsbalk Betonaanstort'; 'kanaalplaat'; 'Kolom'; 'Ligger'; 'Decke'; 'Wand'; 'Dach'};
            app.intheirListBox_8Label = uilabel(app.UpdateclassnamesTab);
            app.intheirListBox_8Label.Text = 'in their';
            app.ToUpdateClass_intheir_structListBox = uilistbox(app.UpdateclassnamesTab);
            app.ToUpdateClass_intheir_structListBox.Items = {'name', 'class'};
            app.ToUpdateClass_intheir_structListBox.ItemsData = {'Name', 'Class'};
            app.ToUpdateClass_intheir_structListBox.Value = 'Name';
            app.ignorecaseListBox_5Label = uilabel(app.UpdateclassnamesTab);
            app.ignorecaseListBox_5Label.Text = 'ignore case?';
            app.ToUpdateClass_ignorecase_structListBox = uilistbox(app.UpdateclassnamesTab);
            app.ToUpdateClass_ignorecase_structListBox.Items = {'Y', 'N'};
            app.ToUpdateClass_ignorecase_structListBox.Value = 'Y';
            app.UpdatematerialnamesTab = uitab(app.TabGroup5);
            app.UpdatematerialnamesTab.Title = 'Update material names';
            app.UpdatematerialnamesLabel = uilabel(app.UpdatematerialnamesTab);
            app.UpdatematerialnamesLabel.Text = {'This tool recognises the following material names:'; '            Staal, Pile, Beton, Hout, Prefab beton.'; 'Recognisable names should be used to correctly map compo with the database.'; ''; 'This section assigns recognisable ones to compo, based on their class.'};
            app.ToUpdateMaterial_Updatecompolist_structButton = uibutton(app.UpdatematerialnamesTab, 'push');
            app.ToUpdateMaterial_Updatecompolist_structButton.ButtonPushedFcn = createCallbackFcn(app, @ToUpdateMaterial_Updatecompolist_structButtonPushed, true);
            app.ToUpdateMaterial_Updatecompolist_structButton.Text = 'Update compo list ';
            app.ForeachoftheseclassesTextAreaLabel = uilabel(app.UpdatematerialnamesTab);
            app.ForeachoftheseclassesTextAreaLabel.Text = 'For each of these classes';
            app.ToUpdateMaterial_Foreachof_structTextArea = uitextarea(app.UpdatematerialnamesTab);
            app.ToUpdateMaterial_Foreachof_structTextArea.Value = {'Pile'; 'FoundationBeam'; 'Betonaanstort'; 'kanaalplaat'; 'Column'; 'Beam'; 'Slab'; 'Wall'; 'Roof'};
            app.writerecognisablematerialnameinsequenceLabel = uilabel(app.UpdatematerialnamesTab);
            app.writerecognisablematerialnameinsequenceLabel.Text = 'write recognisable material name (in sequence) ';
            app.ToUpdateMaterial_writetheirmaterial_structTextArea = uitextarea(app.UpdatematerialnamesTab);
            app.ToUpdateMaterial_writetheirmaterial_structTextArea.Value = {'Pile'; 'Beton'; 'Beton'; 'Beton'; 'Hout'; 'Hout'; 'Prefab beton'; 'Prefab beton'; 'Prefab beton'};
            app.CurrentmaterialnamesPanel = uipanel(app.UpdatematerialnamesTab);
            app.CurrentmaterialnamesPanel.Title = 'Current material names:';
            app.Currentmaterialnames_structTextArea = uitextarea(app.CurrentmaterialnamesPanel);
            app.Currentmaterialnames_structTextArea.Editable = 'off';
            app.Currentmaterialnames_structTextArea.Value = {'Pile'; 'FoundationBeam'; 'Betonaanstort'; 'kanaalplaat'; 'Column'; 'Beam'; 'Slab'; 'Wall'; 'Roof'};
            app.MEPcompoTab = uitab(app.TabGroup4);
            app.MEPcompoTab.Title = 'MEP compo';
            app.ReadfileButton_mepCompo = uibutton(app.MEPcompoTab, 'push');
            app.ReadfileButton_mepCompo.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButton_mepCompoPushed, true);
            app.ReadfileButton_mepCompo.Text = 'Read file';
            app.ReadMEPcomponentlistLabel = uilabel(app.MEPcompoTab);
            app.ReadMEPcomponentlistLabel.Text = 'Read MEP component list';
            app.UITable_mepCompo = uitable(app.MEPcompoTab);
            app.UITable_mepCompo.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_mepCompo.RowName = {};
            app.DirectoryEditField_3Label = uilabel(app.MEPcompoTab);
            app.DirectoryEditField_3Label.Text = 'Directory';
            app.Directory_mepCompoEditField = uieditfield(app.MEPcompoTab, 'text');
            app.Directory_mepCompoEditField.Value = 'E:\THESIS\software\Case\Sample case_mep.xlsx';
            app.FurtherprocesstheMEPcompolistLabel = uilabel(app.MEPcompoTab);
            app.FurtherprocesstheMEPcompolistLabel.Text = 'Further process the MEP compo list:';
            app.TabGroup5_2 = uitabgroup(app.MEPcompoTab);
            app.IfapplicableChangeunitTab_2 = uitab(app.TabGroup5_2);
            app.IfapplicableChangeunitTab_2.Title = '(If applicable) Change unit';
            app.Updatecompolist_mep_changeUnitButton = uibutton(app.IfapplicableChangeunitTab_2, 'push');
            app.Updatecompolist_mep_changeUnitButton.ButtonPushedFcn = createCallbackFcn(app, @Updatecompolist_mep_changeUnitButtonPushed, true);
            app.Updatecompolist_mep_changeUnitButton.Text = 'Update compo list';
            app.NeedtoconvertfrommtommSwitch_2Label = uilabel(app.IfapplicableChangeunitTab_2);
            app.NeedtoconvertfrommtommSwitch_2Label.Text = 'Need to convert from m to mm?';
            app.Needtoconvertfrommtomm_mepSwitch = uiswitch(app.IfapplicableChangeunitTab_2, 'slider');
            app.Needtoconvertfrommtomm_mepSwitch.Items = {'N', 'Y'};
            app.Needtoconvertfrommtomm_mepSwitch.Value = 'N';
            app.TochangeunitofsomecolumnsegfrommtommLabel_3 = uilabel(app.IfapplicableChangeunitTab_2);
            app.TochangeunitofsomecolumnsegfrommtommLabel_3.Text = {'The unit of all length-related values should be mm, e.g. values of coordinates or size.'; 'In case some values are in m, should change the unit from m to mm.'};
            app.ConverttheunitsofthesevaluesfrommtommListBox_2Label = uilabel(app.IfapplicableChangeunitTab_2);
            app.ConverttheunitsofthesevaluesfrommtommListBox_2Label.Text = 'Convert the units of these values from m to mm:';
            app.Converttheunitsofthesevalues_mepListBox = uilistbox(app.IfapplicableChangeunitTab_2);
            app.Converttheunitsofthesevalues_mepListBox.Items = {'CentroidX', 'CentroidY', 'CentroidZ', 'Size_X', 'Size_Y', 'Size_Z'};
            app.Converttheunitsofthesevalues_mepListBox.Multiselect = 'on';
            app.Converttheunitsofthesevalues_mepListBox.Value = {'CentroidX'};
            app.clickonlyonceLabel_2 = uilabel(app.IfapplicableChangeunitTab_2);
            app.clickonlyonceLabel_2.Text = '(click only once!)';
            app.KeepdiscardcompoTab_2 = uitab(app.TabGroup5_2);
            app.KeepdiscardcompoTab_2.Title = 'Keep/discard compo';
            app.thosecompowiththefollowingwordsTextArea_3Label = uilabel(app.KeepdiscardcompoTab_2);
            app.thosecompowiththefollowingwordsTextArea_3Label.Text = 'those compo with the following words ';
            app.Tokeepdiscard_thosecompowith_mep_rule1TextArea = uitextarea(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_thosecompowith_mep_rule1TextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.ToListBox_3Label = uilabel(app.KeepdiscardcompoTab_2);
            app.ToListBox_3Label.Text = 'To';
            app.Tokeepdiscard_mep_rule1ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_mep_rule1ListBox.Items = {'keep', 'discard'};
            app.Tokeepdiscard_mep_rule1ListBox.Value = 'keep';
            app.intheirListBox_6Label = uilabel(app.KeepdiscardcompoTab_2);
            app.intheirListBox_6Label.Text = 'in their';
            app.Tokeepdiscard_intheir_mep_rule1ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_intheir_mep_rule1ListBox.Items = {'name', 'class'};
            app.Tokeepdiscard_intheir_mep_rule1ListBox.ItemsData = {'Name', 'Class'};
            app.Tokeepdiscard_intheir_mep_rule1ListBox.Value = 'Name';
            app.thosecompowiththefollowingwordsTextArea_4Label = uilabel(app.KeepdiscardcompoTab_2);
            app.thosecompowiththefollowingwordsTextArea_4Label.Text = 'those compo with the following words ';
            app.Tokeepdiscard_thosecompowith_mep_rule2TextArea = uitextarea(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_thosecompowith_mep_rule2TextArea.Value = {'Flow fitting'};
            app.ToListBox_4Label = uilabel(app.KeepdiscardcompoTab_2);
            app.ToListBox_4Label.Text = 'To';
            app.Tokeepdiscard_mep_rule2ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_mep_rule2ListBox.Items = {'keep', 'discard'};
            app.Tokeepdiscard_mep_rule2ListBox.Value = 'discard';
            app.intheirListBox_7Label = uilabel(app.KeepdiscardcompoTab_2);
            app.intheirListBox_7Label.Text = 'in their';
            app.Tokeepdiscard_intheir_mep_rule2ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_intheir_mep_rule2ListBox.Items = {'name', 'class'};
            app.Tokeepdiscard_intheir_mep_rule2ListBox.ItemsData = {'Name', 'Class'};
            app.Tokeepdiscard_intheir_mep_rule2ListBox.Value = 'Class';
            app.ignorecaseListBox_3Label = uilabel(app.KeepdiscardcompoTab_2);
            app.ignorecaseListBox_3Label.Text = 'ignore case?';
            app.Tokeepdiscard_ignorecase_mep_rule1ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_ignorecase_mep_rule1ListBox.Items = {'Y', 'N'};
            app.Tokeepdiscard_ignorecase_mep_rule1ListBox.Value = 'Y';
            app.ignorecaseListBox_4Label = uilabel(app.KeepdiscardcompoTab_2);
            app.ignorecaseListBox_4Label.Text = 'ignore case?';
            app.Tokeepdiscard_ignorecase_mep_rule2ListBox = uilistbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_ignorecase_mep_rule2ListBox.Items = {'Y', 'N'};
            app.Tokeepdiscard_ignorecase_mep_rule2ListBox.Value = 'Y';
            app.Tokeepdiscard_Keepdiscardcompo_mepLabel = uilabel(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_Keepdiscardcompo_mepLabel.Text = {'Not all compo are needed in schedule.'; 'Recommend to keep only the main structural compo, and discard others (e.g. small connections), using one or more filtering rules:'};
            app.Tokeepdiscard_Applyfilteringrule1_mepCheckBox = uicheckbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_Applyfilteringrule1_mepCheckBox.Text = 'Apply filtering rule 1:';
            app.Tokeepdiscard_Applyfilteringrule2_mepCheckBox = uicheckbox(app.KeepdiscardcompoTab_2);
            app.Tokeepdiscard_Applyfilteringrule2_mepCheckBox.Text = 'Apply filtering rule 2:';
            app.Tokeepdiscard_Applyfilteringrule2_mepCheckBox.Value = true;
            app.Tokeepdiscard_Updatecompolist_mepButton = uibutton(app.KeepdiscardcompoTab_2, 'push');
            app.Tokeepdiscard_Updatecompolist_mepButton.ButtonPushedFcn = createCallbackFcn(app, @Tokeepdiscard_Updatecompolist_mepButtonPushed, true);
            app.Tokeepdiscard_Updatecompolist_mepButton.Text = 'Update compo list ';
            app.UpdateclassnamesTab_2 = uitab(app.TabGroup5_2);
            app.UpdateclassnamesTab_2.Title = 'Update class names';
            app.UpdateclassnamesLabel_2 = uilabel(app.UpdateclassnamesTab_2);
            app.UpdateclassnamesLabel_2.Text = {'This tool recognises the following class names:'; '            Pile, FoundationBeam, Betonaanstort, kanaalplaat, Column, Beam, Slab, Wall, Roof.'; 'Recognisable names should be used to correctly map compo with the database.'; ''; 'This section replaces current class names by recognisable ones.'};
            app.ToUpdateClass_Updatecompolist_mepButton = uibutton(app.UpdateclassnamesTab_2, 'push');
            app.ToUpdateClass_Updatecompolist_mepButton.ButtonPushedFcn = createCallbackFcn(app, @ToUpdateClass_Updatecompolist_mepButtonPushed, true);
            app.ToUpdateClass_Updatecompolist_mepButton.Text = 'Update compo list ';
            app.CurrentclassnamesPanel_2 = uipanel(app.UpdateclassnamesTab_2);
            app.CurrentclassnamesPanel_2.Title = 'Current class names:';
            app.Currentclassnames_mepTextArea = uitextarea(app.CurrentclassnamesPanel_2);
            app.Currentclassnames_mepTextArea.Editable = 'off';
            app.Currentclassnames_mepTextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.intheirListBox_8Label_2 = uilabel(app.UpdateclassnamesTab_2);
            app.intheirListBox_8Label_2.Text = 'in their';
            app.ToUpdateClass_intheir_mepListBox = uilistbox(app.UpdateclassnamesTab_2);
            app.ToUpdateClass_intheir_mepListBox.Items = {'name', 'class'};
            app.ToUpdateClass_intheir_mepListBox.ItemsData = {'Name', 'Class'};
            app.ToUpdateClass_intheir_mepListBox.Value = 'Name';
            app.ignorecaseListBox_5Label_2 = uilabel(app.UpdateclassnamesTab_2);
            app.ignorecaseListBox_5Label_2.Text = 'ignore case?';
            app.ToUpdateClass_ignorecase_mepListBox = uilistbox(app.UpdateclassnamesTab_2);
            app.ToUpdateClass_ignorecase_mepListBox.Items = {'Y', 'N'};
            app.ToUpdateClass_ignorecase_mepListBox.Value = 'Y';
            app.ForcompowiththesewordsTextAreaLabel_2 = uilabel(app.UpdateclassnamesTab_2);
            app.ForcompowiththesewordsTextAreaLabel_2.Text = 'For compo with these words';
            app.ToUpdateClass_Forcompowith_mepTextArea = uitextarea(app.UpdateclassnamesTab_2);
            app.ToUpdateClass_Forcompowith_mepTextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.writerecognisableclassnameinsequenceLabel_2 = uilabel(app.UpdateclassnamesTab_2);
            app.writerecognisableclassnameinsequenceLabel_2.Text = ', write recognisable class name (in sequence) ';
            app.ToUpdateClass_writerecognisable_mepTextArea = uitextarea(app.UpdateclassnamesTab_2);
            app.ToUpdateClass_writerecognisable_mepTextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.UpdatematerialnamesTab_2 = uitab(app.TabGroup5_2);
            app.UpdatematerialnamesTab_2.Title = 'Update material names';
            app.UpdatematerialnamesLabel_2 = uilabel(app.UpdatematerialnamesTab_2);
            app.UpdatematerialnamesLabel_2.Text = {'This tool recognises the following material names:'; '            Staal, Pile, Beton, Hout, Prefab beton.'; 'Recognisable names should be used to correctly map compo with the database.'; ''; 'This section assigns recognisable ones to compo, based on their class.'};
            app.ToUpdateMaterial_Updatecompolist_mepButton = uibutton(app.UpdatematerialnamesTab_2, 'push');
            app.ToUpdateMaterial_Updatecompolist_mepButton.ButtonPushedFcn = createCallbackFcn(app, @ToUpdateMaterial_Updatecompolist_mepButtonPushed, true);
            app.ToUpdateMaterial_Updatecompolist_mepButton.Text = 'Update compo list ';
            app.ForeachoftheseclassesTextArea_4Label = uilabel(app.UpdatematerialnamesTab_2);
            app.ForeachoftheseclassesTextArea_4Label.Text = 'For each of these classes';
            app.ToUpdateMaterial_Foreachof_mepTextArea = uitextarea(app.UpdatematerialnamesTab_2);
            app.ToUpdateMaterial_Foreachof_mepTextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.writerecognisablematerialnameinsequenceLabel_2 = uilabel(app.UpdatematerialnamesTab_2);
            app.writerecognisablematerialnameinsequenceLabel_2.Text = 'write recognisable material name (in sequence) ';
            app.ToUpdateMaterial_writetheirmaterial_mepTextArea = uitextarea(app.UpdatematerialnamesTab_2);
            app.ToUpdateMaterial_writetheirmaterial_mepTextArea.Value = {'FlowSegmentMat'; 'FlowFittingMat'};
            app.CurrentmaterialnamesPanel_2 = uipanel(app.UpdatematerialnamesTab_2);
            app.CurrentmaterialnamesPanel_2.Title = 'Current material names:';
            app.Currentmaterialnames_mepTextArea = uitextarea(app.CurrentmaterialnamesPanel_2);
            app.Currentmaterialnames_mepTextArea.Editable = 'off';
            app.Currentmaterialnames_mepTextArea.Value = {'Flow segment'; 'Flow fitting'};
            app.StructuralMEPdatabaseTab = uitab(app.TabGroup4);
            app.StructuralMEPdatabaseTab.Title = 'Structural & MEP database';
            app.ReadfileButton_database = uibutton(app.StructuralMEPdatabaseTab, 'push');
            app.ReadfileButton_database.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButton_structdatabasePushed, true);
            app.ReadfileButton_database.Text = 'Read file';
            app.DatabaseLabel_3 = uilabel(app.StructuralMEPdatabaseTab);
            app.DatabaseLabel_3.Text = 'Database';
            app.UITable_database = uitable(app.StructuralMEPdatabaseTab);
            app.UITable_database.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_database.RowName = {};
            app.DirectoryEditField_6Label = uilabel(app.StructuralMEPdatabaseTab);
            app.DirectoryEditField_6Label.Text = 'Directory';
            app.Directory_databaseEditField = uieditfield(app.StructuralMEPdatabaseTab, 'text');
            app.Directory_databaseEditField.Value = 'E:\THESIS\software\Case\database_v5.xlsx';
            app.SummaryTab = uitab(app.TabGroup4);
            app.SummaryTab.Title = 'Summary';
            app.StructuralclassesEditFieldLabel = uilabel(app.SummaryTab);
            app.StructuralclassesEditFieldLabel.Text = 'Structural classes';
            app.StructuralclassesEditField = uieditfield(app.SummaryTab, 'text');
            app.StructuralclassesEditField.Editable = 'off';
            app.StructuralclassesEditField.Tooltip = {'Lists the classes in the compo list'};
            app.MEPclassesEditFieldLabel = uilabel(app.SummaryTab);
            app.MEPclassesEditFieldLabel.Text = 'MEP classes';
            app.MEPclassesEditField = uieditfield(app.SummaryTab, 'text');
            app.MEPclassesEditField.Editable = 'off';
            app.MEPclassesEditField.Tooltip = {'Lists the classes in the compo list'};
            app.ReadlistsofcomponentanddatabaseLabel = uilabel(app.TabData1);
            app.ReadlistsofcomponentanddatabaseLabel.Text = 'Read lists of component and database';
            app.TabData2 = uitab(app.TabGroup);
            app.TabData2.Title = '1. Levels';
            app.TabGroup3 = uitabgroup(app.TabData2);
            app.ElevationdataTab = uitab(app.TabGroup3);
            app.ElevationdataTab.Title = 'Elevation data';
            app.Image_3 = uiimage(app.ElevationdataTab);
            app.Image_3.ImageSource = 'floorLevel_building.PNG';
            app.InputH_0toH_ninsequenceLabel = uilabel(app.ElevationdataTab);
            app.InputH_0toH_ninsequenceLabel.Interpreter = 'latex';
            app.InputH_0toH_ninsequenceLabel.Text = 'Input \(H_0\) to \(H_n\) (in sequence):';
            app.H0toHnTextArea = uitextarea(app.ElevationdataTab);
            app.H0toHnTextArea.Tooltip = {'Unit: mm'};
            app.H0toHnTextArea.Value = {'100'; '4100'; '8100'; '12150'};
            app.ElevationExplanationLabel = uilabel(app.ElevationdataTab);
            app.ElevationExplanationLabel.Text = {'First, write the elevation of all floors.'; ''; 'Next, for structural & MEP compo respectively, tell the tool which level a compo should be categorised into, for a series of scenarios.'; ''; 'All height data in mm.'};
            app.NumtypesofresourcesEditFieldLabel = uilabel(app.ElevationdataTab);
            app.NumtypesofresourcesEditFieldLabel.Text = 'Num. types of resources';
            app.nREditField = uieditfield(app.ElevationdataTab, 'numeric');
            app.nREditField.Limits = [1 4];
            app.nREditField.Value = 2;
            app.NumactivityexecutionmodesEditFieldLabel = uilabel(app.ElevationdataTab);
            app.NumactivityexecutionmodesEditFieldLabel.Text = 'Num. activity execution modes';
            app.nMEditField = uieditfield(app.ElevationdataTab, 'numeric');
            app.nMEditField.Limits = [1 2];
            app.nMEditField.Value = 2;
            app.FloorlevelofstructuralcompoTab = uitab(app.TabGroup3);
            app.FloorlevelofstructuralcompoTab.Title = 'Floor level of structural compo';
            app.Image2 = uiimage(app.FloorlevelofstructuralcompoTab);
            app.Image2.ImageSource = 'floorLevel_compo_9compo.PNG';
            app.Compo1ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo1ButtonGroup.Title = 'Compo 1';
            app.nFButton = uiradiobutton(app.Compo1ButtonGroup);
            app.nFButton.Text = '(n) F';
            app.nFButton.Value = true;
            app.n1FButton = uiradiobutton(app.Compo1ButtonGroup);
            app.n1FButton.Text = '(n+1) F';
            app.n2FButton = uiradiobutton(app.Compo1ButtonGroup);
            app.n2FButton.Text = '(n+2) F';
            app.Compo2ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo2ButtonGroup.Title = 'Compo 2';
            app.nFButton_2 = uiradiobutton(app.Compo2ButtonGroup);
            app.nFButton_2.Text = '(n) F';
            app.nFButton_2.Value = true;
            app.n1FButton_2 = uiradiobutton(app.Compo2ButtonGroup);
            app.n1FButton_2.Text = '(n+1) F';
            app.n2FButton_2 = uiradiobutton(app.Compo2ButtonGroup);
            app.n2FButton_2.Text = '(n+2) F';
            app.Compo3ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo3ButtonGroup.Title = 'Compo 3';
            app.nFButton_3 = uiradiobutton(app.Compo3ButtonGroup);
            app.nFButton_3.Text = '(n) F';
            app.n1FButton_3 = uiradiobutton(app.Compo3ButtonGroup);
            app.n1FButton_3.Text = '(n+1) F';
            app.n1FButton_3.Value = true;
            app.n2FButton_3 = uiradiobutton(app.Compo3ButtonGroup);
            app.n2FButton_3.Text = '(n+2) F';
            app.Compo4ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo4ButtonGroup.Title = 'Compo 4';
            app.nFButton_4 = uiradiobutton(app.Compo4ButtonGroup);
            app.nFButton_4.Text = '(n) F';
            app.n1FButton_4 = uiradiobutton(app.Compo4ButtonGroup);
            app.n1FButton_4.Text = '(n+1) F';
            app.n1FButton_4.Value = true;
            app.n2FButton_4 = uiradiobutton(app.Compo4ButtonGroup);
            app.n2FButton_4.Text = '(n+2) F';
            app.Compo5ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo5ButtonGroup.Title = 'Compo 5';
            app.nFButton_5 = uiradiobutton(app.Compo5ButtonGroup);
            app.nFButton_5.Text = '(n) F';
            app.n1FButton_5 = uiradiobutton(app.Compo5ButtonGroup);
            app.n1FButton_5.Text = '(n+1) F';
            app.n1FButton_5.Value = true;
            app.n2FButton_5 = uiradiobutton(app.Compo5ButtonGroup);
            app.n2FButton_5.Text = '(n+2) F';
            app.Compo6ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo6ButtonGroup.Title = 'Compo 6';
            app.nFButton_6 = uiradiobutton(app.Compo6ButtonGroup);
            app.nFButton_6.Text = '(n) F';
            app.n1FButton_6 = uiradiobutton(app.Compo6ButtonGroup);
            app.n1FButton_6.Text = '(n+1) F';
            app.n1FButton_6.Value = true;
            app.n2FButton_6 = uiradiobutton(app.Compo6ButtonGroup);
            app.n2FButton_6.Text = '(n+2) F';
            app.Compo7ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo7ButtonGroup.Title = 'Compo 7';
            app.nFButton_7 = uiradiobutton(app.Compo7ButtonGroup);
            app.nFButton_7.Text = '(n) F';
            app.n1FButton_7 = uiradiobutton(app.Compo7ButtonGroup);
            app.n1FButton_7.Text = '(n+1) F';
            app.n2FButton_7 = uiradiobutton(app.Compo7ButtonGroup);
            app.n2FButton_7.Text = '(n+2) F';
            app.n2FButton_7.Value = true;
            app.Compo8ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo8ButtonGroup.Title = 'Compo 8';
            app.nFButton_8 = uiradiobutton(app.Compo8ButtonGroup);
            app.nFButton_8.Text = '(n) F';
            app.n1FButton_8 = uiradiobutton(app.Compo8ButtonGroup);
            app.n1FButton_8.Text = '(n+1) F';
            app.n2FButton_8 = uiradiobutton(app.Compo8ButtonGroup);
            app.n2FButton_8.Text = '(n+2) F';
            app.n2FButton_8.Value = true;
            app.SpecifywhichlevelthesecompoareassignedtoLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.SpecifywhichlevelthesecompoareassignedtoLabel.Text = 'Specify which level these compo are assigned to:';
            app.DeltaH_structEditFieldLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.DeltaH_structEditFieldLabel.Interpreter = 'latex';
            app.DeltaH_structEditFieldLabel.Text = '\(\Delta H_{struct}\)';
            app.DeltaH_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.DeltaH_structEditField.Limits = [0 4000];
            app.DeltaH_structEditField.RoundFractionalValues = 'on';
            app.DeltaH_structEditField.Tooltip = {'Unit: mm'};
            app.DeltaH_structEditField.Value = 300;
            app.ElevationExplanation_structLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.ElevationExplanation_structLabel.Text = 'Tell the tool which level a compo should be categorised into, in 9 scenarios.';
            app.Compo9ButtonGroup = uibuttongroup(app.FloorlevelofstructuralcompoTab);
            app.Compo9ButtonGroup.Title = 'Compo 9';
            app.nFButton_17 = uiradiobutton(app.Compo9ButtonGroup);
            app.nFButton_17.Text = '(n) F';
            app.n1FButton_17 = uiradiobutton(app.Compo9ButtonGroup);
            app.n1FButton_17.Text = '(n+1) F';
            app.n1FButton_17.Value = true;
            app.n2FButton_17 = uiradiobutton(app.Compo9ButtonGroup);
            app.n2FButton_17.Text = '(n+2) F';
            app.Specialcase_structLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.Specialcase_structLabel.Text = 'Special cases';
            app.Floor_S1_structCheckBox = uicheckbox(app.FloorlevelofstructuralcompoTab);
            app.Floor_S1_structCheckBox.Text = 'Special case 1';
            app.Floor_S1_structCheckBox.Value = true;
            app.ThosecompowhichareinclassDropDownLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.ThosecompowhichareinclassDropDownLabel.Text = 'Those compo which are in class ';
            app.Floor_S1_Thosecompo_structDropDown = uidropdown(app.FloorlevelofstructuralcompoTab);
            app.Floor_S1_Thosecompo_structDropDown.Items = {'-', 'Column', 'Beam', 'Slab', 'Wall'};
            app.Floor_S1_Thosecompo_structDropDown.Value = 'Column';
            app.andhasLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.andhasLabel.Text = ', and has';
            app.Floor_S1_andhas_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S1_andhas_structEditField.Value = -1000;
            app.lowerendEditFieldLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.lowerendEditFieldLabel.Text = '< lower end <';
            app.Floor_S1_lowerend_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S1_lowerend_structEditField.Value = 1000;
            app.andEditFieldLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.andEditFieldLabel.Text = 'and ';
            app.Floor_S1_and_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S1_and_structEditField.Value = 3000;
            app.upperendEditFieldLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.upperendEditFieldLabel.Text = '< upper end <';
            app.Floor_S1_upperend_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S1_upperend_structEditField.Value = 5000;
            app.areassignedtofloorlevelEditFieldLabel = uilabel(app.FloorlevelofstructuralcompoTab);
            app.areassignedtofloorlevelEditFieldLabel.Text = 'are assigned to floor level';
            app.Floor_S1_areassignedto_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S1_areassignedto_structEditField.Tooltip = {'Use one digit,'; ''; 'e.g. input "1" for floor level 1F.'};
            app.Floor_S2_structCheckBox = uicheckbox(app.FloorlevelofstructuralcompoTab);
            app.Floor_S2_structCheckBox.Text = 'Special case 2';
            app.Floor_S2_structCheckBox.Value = true;
            app.ThosecompowhichareinclassDropDownLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.ThosecompowhichareinclassDropDownLabel_2.Text = 'Those compo which are in class ';
            app.Floor_S2_Thosecompo_structDropDown = uidropdown(app.FloorlevelofstructuralcompoTab);
            app.Floor_S2_Thosecompo_structDropDown.Items = {'-', 'Column', 'Beam', 'Slab', 'Wall'};
            app.Floor_S2_Thosecompo_structDropDown.Value = 'Wall';
            app.andhasLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.andhasLabel_2.Text = ', and has';
            app.Floor_S2_andhas_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S2_andhas_structEditField.Value = -1000;
            app.lowerendEditFieldLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.lowerendEditFieldLabel_2.Text = '< lower end <';
            app.Floor_S2_lowerend_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S2_lowerend_structEditField.Value = 1000;
            app.andEditFieldLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.andEditFieldLabel_2.Text = 'and ';
            app.Floor_S2_and_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S2_and_structEditField.Value = 10000;
            app.upperendEditFieldLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.upperendEditFieldLabel_2.Text = '< upper end <';
            app.Floor_S2_upperend_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S2_upperend_structEditField.Value = Inf;
            app.areassignedtofloorlevelEditFieldLabel_2 = uilabel(app.FloorlevelofstructuralcompoTab);
            app.areassignedtofloorlevelEditFieldLabel_2.Text = 'are assigned to floor level';
            app.Floor_S2_areassignedto_structEditField = uieditfield(app.FloorlevelofstructuralcompoTab, 'numeric');
            app.Floor_S2_areassignedto_structEditField.Tooltip = {'Use one digit,'; ''; 'e.g. input "1" for floor level 1F.'};
            app.FloorlevelofMEPcompoTab = uitab(app.TabGroup3);
            app.FloorlevelofMEPcompoTab.Title = 'Floor level of MEP compo';
            app.Image2_2 = uiimage(app.FloorlevelofMEPcompoTab);
            app.Image2_2.ImageSource = 'floorLevel_compo_9compo.PNG';
            app.Compo1ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo1ButtonGroup_2.Title = 'Compo 1';
            app.nFButton_9 = uiradiobutton(app.Compo1ButtonGroup_2);
            app.nFButton_9.Text = '(n) F';
            app.nFButton_9.Value = true;
            app.n1FButton_9 = uiradiobutton(app.Compo1ButtonGroup_2);
            app.n1FButton_9.Text = '(n+1) F';
            app.n2FButton_9 = uiradiobutton(app.Compo1ButtonGroup_2);
            app.n2FButton_9.Text = '(n+2) F';
            app.Compo2ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo2ButtonGroup_2.Title = 'Compo 2';
            app.nFButton_10 = uiradiobutton(app.Compo2ButtonGroup_2);
            app.nFButton_10.Text = '(n) F';
            app.n1FButton_10 = uiradiobutton(app.Compo2ButtonGroup_2);
            app.n1FButton_10.Text = '(n+1) F';
            app.n1FButton_10.Value = true;
            app.n2FButton_10 = uiradiobutton(app.Compo2ButtonGroup_2);
            app.n2FButton_10.Text = '(n+2) F';
            app.Compo3ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo3ButtonGroup_2.Title = 'Compo 3';
            app.nFButton_11 = uiradiobutton(app.Compo3ButtonGroup_2);
            app.nFButton_11.Text = '(n) F';
            app.n1FButton_11 = uiradiobutton(app.Compo3ButtonGroup_2);
            app.n1FButton_11.Text = '(n+1) F';
            app.n1FButton_11.Value = true;
            app.n2FButton_11 = uiradiobutton(app.Compo3ButtonGroup_2);
            app.n2FButton_11.Text = '(n+2) F';
            app.Compo4ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo4ButtonGroup_2.Title = 'Compo 4';
            app.nFButton_12 = uiradiobutton(app.Compo4ButtonGroup_2);
            app.nFButton_12.Text = '(n) F';
            app.n1FButton_12 = uiradiobutton(app.Compo4ButtonGroup_2);
            app.n1FButton_12.Text = '(n+1) F';
            app.n1FButton_12.Value = true;
            app.n2FButton_12 = uiradiobutton(app.Compo4ButtonGroup_2);
            app.n2FButton_12.Text = '(n+2) F';
            app.Compo5ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo5ButtonGroup_2.Title = 'Compo 5';
            app.nFButton_13 = uiradiobutton(app.Compo5ButtonGroup_2);
            app.nFButton_13.Text = '(n) F';
            app.n1FButton_13 = uiradiobutton(app.Compo5ButtonGroup_2);
            app.n1FButton_13.Text = '(n+1) F';
            app.n2FButton_13 = uiradiobutton(app.Compo5ButtonGroup_2);
            app.n2FButton_13.Text = '(n+2) F';
            app.n2FButton_13.Value = true;
            app.Compo6ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo6ButtonGroup_2.Title = 'Compo 6';
            app.nFButton_14 = uiradiobutton(app.Compo6ButtonGroup_2);
            app.nFButton_14.Text = '(n) F';
            app.n1FButton_14 = uiradiobutton(app.Compo6ButtonGroup_2);
            app.n1FButton_14.Text = '(n+1) F';
            app.n2FButton_14 = uiradiobutton(app.Compo6ButtonGroup_2);
            app.n2FButton_14.Text = '(n+2) F';
            app.n2FButton_14.Value = true;
            app.Compo7ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo7ButtonGroup_2.Title = 'Compo 7';
            app.nFButton_15 = uiradiobutton(app.Compo7ButtonGroup_2);
            app.nFButton_15.Text = '(n) F';
            app.n1FButton_15 = uiradiobutton(app.Compo7ButtonGroup_2);
            app.n1FButton_15.Text = '(n+1) F';
            app.n2FButton_15 = uiradiobutton(app.Compo7ButtonGroup_2);
            app.n2FButton_15.Text = '(n+2) F';
            app.n2FButton_15.Value = true;
            app.Compo8ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo8ButtonGroup_2.Title = 'Compo 8';
            app.nFButton_16 = uiradiobutton(app.Compo8ButtonGroup_2);
            app.nFButton_16.Text = '(n) F';
            app.n1FButton_16 = uiradiobutton(app.Compo8ButtonGroup_2);
            app.n1FButton_16.Text = '(n+1) F';
            app.n2FButton_16 = uiradiobutton(app.Compo8ButtonGroup_2);
            app.n2FButton_16.Text = '(n+2) F';
            app.n2FButton_16.Value = true;
            app.SpecifywhichlevelthesecompoareassignedtoLabel_2 = uilabel(app.FloorlevelofMEPcompoTab);
            app.SpecifywhichlevelthesecompoareassignedtoLabel_2.Text = 'Specify which level these compo are assigned to:';
            app.DeltaH_mepEditFieldLabel = uilabel(app.FloorlevelofMEPcompoTab);
            app.DeltaH_mepEditFieldLabel.Interpreter = 'latex';
            app.DeltaH_mepEditFieldLabel.Text = '\(\Delta H_{mep}\)';
            app.DeltaH_mepEditField = uieditfield(app.FloorlevelofMEPcompoTab, 'numeric');
            app.DeltaH_mepEditField.Limits = [0 4000];
            app.DeltaH_mepEditField.RoundFractionalValues = 'on';
            app.DeltaH_mepEditField.Tooltip = {'Unit: mm'};
            app.DeltaH_mepEditField.Value = 190;
            app.ElevationExplanation_mepLabel = uilabel(app.FloorlevelofMEPcompoTab);
            app.ElevationExplanation_mepLabel.Text = 'Tell the tool which level a compo should be categorised into, in 9 scenarios.';
            app.Compo9ButtonGroup_2 = uibuttongroup(app.FloorlevelofMEPcompoTab);
            app.Compo9ButtonGroup_2.Title = 'Compo 9';
            app.nFButton_18 = uiradiobutton(app.Compo9ButtonGroup_2);
            app.nFButton_18.Text = '(n) F';
            app.n1FButton_18 = uiradiobutton(app.Compo9ButtonGroup_2);
            app.n1FButton_18.Text = '(n+1) F';
            app.n2FButton_18 = uiradiobutton(app.Compo9ButtonGroup_2);
            app.n2FButton_18.Text = '(n+2) F';
            app.n2FButton_18.Value = true;
            app.ShowatomactivitiesTab = uitab(app.TabGroup3);
            app.ShowatomactivitiesTab.Title = 'Show atom activities';
            app.Shownlistofatomactivities_structButton = uibutton(app.ShowatomactivitiesTab, 'push');
            app.Shownlistofatomactivities_structButton.ButtonPushedFcn = createCallbackFcn(app, @Shownlistofatomactivities_structButtonPushed, true);
            app.Shownlistofatomactivities_structButton.Text = 'Shown list of atom activities - structural';
            app.UITable_structAtomAct = uitable(app.ShowatomactivitiesTab);
            app.UITable_structAtomAct.ColumnName = '';
            app.UITable_structAtomAct.RowName = {};
            app.Shownlistofatomactivities_mepButton = uibutton(app.ShowatomactivitiesTab, 'push');
            app.Shownlistofatomactivities_mepButton.ButtonPushedFcn = createCallbackFcn(app, @Shownlistofatomactivities_mepButtonPushed, true);
            app.Shownlistofatomactivities_mepButton.Text = 'Shown list of atom activities - MEP';
            app.UITable_mepAtomAct = uitable(app.ShowatomactivitiesTab);
            app.UITable_mepAtomAct.ColumnName = '';
            app.UITable_mepAtomAct.RowName = {};
            app.RulesforfloorlevelidentificationofcompoLabel = uilabel(app.TabData2);
            app.RulesforfloorlevelidentificationofcompoLabel.Text = 'Rules for floor level identification of compo';
            app.TabSeq = uitab(app.TabGroup);
            app.TabSeq.Title = '2. Seq rules';
            app.TabGroup2 = uitabgroup(app.TabSeq);
            app.RulesforstructuralcompoTab = uitab(app.TabGroup2);
            app.RulesforstructuralcompoTab.Title = 'Rules for structural compo';
            app.WithineachfloorlevelLabel = uilabel(app.RulesforstructuralcompoTab);
            app.WithineachfloorlevelLabel.Text = 'Within each floor level:';
            app.optionalForthewholebuildingLabel = uilabel(app.RulesforstructuralcompoTab);
            app.optionalForthewholebuildingLabel.Text = '(optional) For the whole building:';
            app.Image = uiimage(app.RulesforstructuralcompoTab);
            app.Image.ImageSource = 'rules4struct_floorLevel.PNG';
            app.Image_2 = uiimage(app.RulesforstructuralcompoTab);
            app.Image_2.ImageSource = 'rules4struct_building.PNG';
            app.EssentialLabel = uilabel(app.RulesforstructuralcompoTab);
            app.EssentialLabel.Tooltip = {'Essential and non-essential structural classes:'; ''; 'Compo in a higher floor can only start if all essential structural compo in the floor below are finished.'; ''; 'Example of essential compo classes: column, beam, slab;'; 'Example of non-essential classes: facade.'};
            app.EssentialLabel.Text = 'Essential? *';
            app.CheckBox = uicheckbox(app.RulesforstructuralcompoTab);
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.RulesforstructuralcompoTab);
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.RulesforstructuralcompoTab);
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.RulesforstructuralcompoTab);
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.RulesforstructuralcompoTab);
            app.Seq1DropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.Seq1DropDownLabel.Text = 'Seq-1';
            app.Seq1DropDown = uidropdown(app.RulesforstructuralcompoTab);
            app.Seq1DropDown.Items = {'Pile', 'Column', 'Beam', 'Slab', 'Wall', 'Facade'};
            app.Seq1DropDown.Value = 'Pile';
            app.Seq2DropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.Seq2DropDownLabel.Text = 'Seq-2';
            app.Seq2DropDown = uidropdown(app.RulesforstructuralcompoTab);
            app.Seq3DropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.Seq3DropDownLabel.Text = 'Seq-3';
            app.Seq3DropDown = uidropdown(app.RulesforstructuralcompoTab);
            app.Seq4DropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.Seq4DropDownLabel.Text = 'Seq-4';
            app.Seq4DropDown = uidropdown(app.RulesforstructuralcompoTab);
            app.classDropDown_2Label = uilabel(app.RulesforstructuralcompoTab);
            app.classDropDown_2Label.Text = 'class';
            app.classDropDown_S1_1 = uidropdown(app.RulesforstructuralcompoTab);
            app.classDropDown_S1_1.Items = {'Column', 'Beam', 'Slab', 'Wall', 'Facade'};
            app.classDropDown_S1_1.Value = 'Wall';
            app.classDropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.classDropDownLabel.Text = 'class';
            app.classDropDown_S1_2 = uidropdown(app.RulesforstructuralcompoTab);
            app.classDropDown_S1_2.Items = {'Column', 'Beam', 'Slab', 'Wall', 'Facade'};
            app.classDropDown_S1_2.Value = 'Column';
            app.atlevelDropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.atlevelDropDownLabel.Text = 'at level';
            app.atlevelDropDown_S1_2 = uidropdown(app.RulesforstructuralcompoTab);
            app.atlevelDropDown_S1_2.Items = {'0', '1', '2'};
            app.atlevelDropDown_S1_2.Value = '0';
            app.atlevelDropDown_2Label = uilabel(app.RulesforstructuralcompoTab);
            app.atlevelDropDown_2Label.Text = 'at level';
            app.atlevelDropDown_S1_1 = uidropdown(app.RulesforstructuralcompoTab);
            app.atlevelDropDown_S1_1.Items = {'0', '1', '2'};
            app.atlevelDropDown_S1_1.Value = '0';
            app.Seq5DropDownLabel = uilabel(app.RulesforstructuralcompoTab);
            app.Seq5DropDownLabel.Text = 'Seq-5';
            app.Seq5DropDown = uidropdown(app.RulesforstructuralcompoTab);
            app.StructuralclassesEditField_2Label = uilabel(app.RulesforstructuralcompoTab);
            app.StructuralclassesEditField_2Label.Text = 'Structural classes';
            app.StructuralclassesEditField_2 = uieditfield(app.RulesforstructuralcompoTab, 'text');
            app.StructuralclassesEditField_2.Editable = 'off';
            app.StructuralclassesEditField_2.Tooltip = {'Lists the classes in the compo list'};
            app.Specialcase1CheckBox = uicheckbox(app.RulesforstructuralcompoTab);
            app.Specialcase1CheckBox.Tooltip = {'The inputs will form the following argument:'; ''; '"Class A" "in level B" is constructed "before/after" "class D" "in level E".'; ''; 'Example: Load-bearing wall in level 0 is constructed before column in level 0.'};
            app.Specialcase1CheckBox.Text = 'Special case 1 *:';
            app.Specialcase1CheckBox.Value = true;
            app.andhasLabel_3 = uilabel(app.RulesforstructuralcompoTab);
            app.andhasLabel_3.Text = ', and has';
            app.andhas_structEditField_S1_1 = uieditfield(app.RulesforstructuralcompoTab, 'numeric');
            app.andhas_structEditField_S1_1.Value = -1000;
            app.lowerendEditFieldLabel_3 = uilabel(app.RulesforstructuralcompoTab);
            app.lowerendEditFieldLabel_3.Text = '< lower end <';
            app.lowerend_structEditField_S1_1 = uieditfield(app.RulesforstructuralcompoTab, 'numeric');
            app.lowerend_structEditField_S1_1.Value = 1000;
            app.andEditFieldLabel_3 = uilabel(app.RulesforstructuralcompoTab);
            app.andEditFieldLabel_3.Text = 'and ';
            app.and_structEditField_S1_1 = uieditfield(app.RulesforstructuralcompoTab, 'numeric');
            app.and_structEditField_S1_1.Value = 10000;
            app.upperendEditFieldLabel_3 = uilabel(app.RulesforstructuralcompoTab);
            app.upperendEditFieldLabel_3.Text = '< upper end <';
            app.upperend_structEditField_S1_1 = uieditfield(app.RulesforstructuralcompoTab, 'numeric');
            app.upperend_structEditField_S1_1.Value = Inf;
            app.shouldbeLabel = uilabel(app.RulesforstructuralcompoTab);
            app.shouldbeLabel.Text = 'should be';
            app.beforeafterDropDown_S1_1 = uidropdown(app.RulesforstructuralcompoTab);
            app.beforeafterDropDown_S1_1.Items = {'before', 'after'};
            app.beforeafterDropDown_S1_1.Value = 'before';
            app.RulesforMEPcompoTab = uitab(app.TabGroup2);
            app.RulesforMEPcompoTab.Title = 'Rules for MEP compo';
            app.SpatialrelationsLabel = uilabel(app.RulesforMEPcompoTab);
            app.SpatialrelationsLabel.Text = 'Spatial relations';
            app.SizerelationslengthandcrosssectionalsizeLabel = uilabel(app.RulesforMEPcompoTab);
            app.SizerelationslengthandcrosssectionalsizeLabel.Text = 'Size relations (length and cross-sectional size):';
            app.Image3 = uiimage(app.RulesforMEPcompoTab);
            app.Image3.ImageSource = 'rules4mep_SSR.PNG';
            app.ChecklengthbeforeareaCheckBox = uicheckbox(app.RulesforMEPcompoTab);
            app.ChecklengthbeforeareaCheckBox.Text = 'Check length before area?';
            app.ChecklengthbeforeareaCheckBox.Value = true;
            app.D_minhEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minhEditFieldLabel.Interpreter = 'latex';
            app.D_minhEditFieldLabel.Text = '\(D_{minh}\)';
            app.D_minhEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minhEditField.Tooltip = {'Unit: mm'};
            app.D_minhEditField.Value = 300;
            app.D_minvEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minvEditFieldLabel.Interpreter = 'latex';
            app.D_minvEditFieldLabel.Text = '\(D_{minv}\)';
            app.D_minvEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minvEditField.Tooltip = {'Unit: mm'};
            app.D_minvEditField.Value = 300;
            app.D_minWallEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minWallEditFieldLabel.Interpreter = 'latex';
            app.D_minWallEditFieldLabel.Text = '\(D_{minWall}\)';
            app.D_minWallEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minWallEditField.Tooltip = {'Unit: mm'};
            app.D_minWallEditField.Value = 800;
            app.D_minCeilEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minCeilEditFieldLabel.Interpreter = 'latex';
            app.D_minCeilEditFieldLabel.Tooltip = {'Unit: mm'};
            app.D_minCeilEditFieldLabel.Text = '\(D_{minCeil}\)';
            app.D_minCeilEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minCeilEditField.Tooltip = {'Unit: mm'};
            app.D_minCeilEditField.Value = 800;
            app.D_minh2EditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minh2EditFieldLabel.Interpreter = 'latex';
            app.D_minh2EditFieldLabel.Text = '\(D_{minh2}\)';
            app.D_minh2EditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minh2EditField.Tooltip = {'Unit: mm'};
            app.D_minh2EditField.Value = 300;
            app.D_minv2EditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.D_minv2EditFieldLabel.Interpreter = 'latex';
            app.D_minv2EditFieldLabel.Text = '\(D_{minv2}\)';
            app.D_minv2EditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.D_minv2EditField.Tooltip = {'Unit: mm'};
            app.D_minv2EditField.Value = 300;
            app.L_longEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.L_longEditFieldLabel.Interpreter = 'latex';
            app.L_longEditFieldLabel.Text = '\(L_{long}\)';
            app.L_longEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.L_longEditField.Tooltip = {'Unit: mm'};
            app.L_longEditField.Value = 12000;
            app.L_shortEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.L_shortEditFieldLabel.Interpreter = 'latex';
            app.L_shortEditFieldLabel.Text = '\(L_{short}\)';
            app.L_shortEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.L_shortEditField.Tooltip = {'Unit: mm'};
            app.L_shortEditField.Value = 1000;
            app.A_largeEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.A_largeEditFieldLabel.Interpreter = 'latex';
            app.A_largeEditFieldLabel.Text = '\(A_{large}\)';
            app.A_largeEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.A_largeEditField.Tooltip = {'Unit: mm'};
            app.A_largeEditField.Value = 80000;
            app.A_smallEditFieldLabel = uilabel(app.RulesforMEPcompoTab);
            app.A_smallEditFieldLabel.Interpreter = 'latex';
            app.A_smallEditFieldLabel.Tooltip = {'Unit: mm'};
            app.A_smallEditFieldLabel.Text = '\(A_{small}\)';
            app.A_smallEditField = uieditfield(app.RulesforMEPcompoTab, 'numeric');
            app.A_smallEditField.Tooltip = {'Unit: mm'};
            app.A_smallEditField.Value = 1200;
            app.MEPclassesEditFieldLabel_2 = uilabel(app.RulesforMEPcompoTab);
            app.MEPclassesEditFieldLabel_2.Text = 'MEP classes';
            app.MEPclassesEditField_2 = uieditfield(app.RulesforMEPcompoTab, 'text');
            app.MEPclassesEditField_2.Editable = 'off';
            app.MEPclassesEditField_2.Tooltip = {'Lists the classes in the compo list'};
            app.SequencingrulesLabel = uilabel(app.TabSeq);
            app.SequencingrulesLabel.Text = 'Sequencing rules';
            app.TabPref = uitab(app.TabGroup);
            app.TabPref.Title = '3. Preferences';
            app.WorkingzonesinmetresPanel = uipanel(app.TabPref);
            app.WorkingzonesinmetresPanel.Title = 'Working zones (in metres)';
            app.BuildingsizealongxmEditFieldLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.BuildingsizealongxmEditFieldLabel.Text = 'Building size along x (m)';
            app.BuildingsizealongxmEditField = uieditfield(app.WorkingzonesinmetresPanel, 'text');
            app.BuildingsizealongxmEditField.Editable = 'off';
            app.BuildingsizealongxmEditField.Tooltip = {'Shows the building size along x (in metres)'};
            app.alongymEditFieldLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.alongymEditFieldLabel.Text = ' along y (m)';
            app.BuildingsizealongymEditField = uieditfield(app.WorkingzonesinmetresPanel, 'text');
            app.BuildingsizealongymEditField.Editable = 'off';
            app.BuildingsizealongymEditField.Tooltip = {'Shows the building size along y (in metres)'};
            app.ConstructionstartingfromwhichcornerDropDownLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.ConstructionstartingfromwhichcornerDropDownLabel.Text = 'Construction starting from which corner:';
            app.ConstructionstartingfromwhichcornerDropDown = uidropdown(app.WorkingzonesinmetresPanel);
            app.ConstructionstartingfromwhichcornerDropDown.Items = {'bottom-left', 'bottom-right', 'top-left', 'top-right'};
            app.ConstructionstartingfromwhichcornerDropDown.Value = 'bottom-left';
            app.thenalongwhichaxisfirstDropDownLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.thenalongwhichaxisfirstDropDownLabel.Text = 'then along which axis first:';
            app.thenalongwhichaxisfirstDropDown = uidropdown(app.WorkingzonesinmetresPanel);
            app.thenalongwhichaxisfirstDropDown.Items = {'x', 'y'};
            app.thenalongwhichaxisfirstDropDown.Value = 'y';
            app.MinzonesizealongxEditField_2Label = uilabel(app.WorkingzonesinmetresPanel);
            app.MinzonesizealongxEditField_2Label.Text = 'Min. zone size along x ';
            app.MinzonesizealongxEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.MinzonesizealongxEditField.Value = 30;
            app.alongyEditField_5Label = uilabel(app.WorkingzonesinmetresPanel);
            app.alongyEditField_5Label.Text = 'along y ';
            app.MinzonesizealongyEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.MinzonesizealongyEditField.Value = 60;
            app.MaxzonesizealongxEditField_2Label = uilabel(app.WorkingzonesinmetresPanel);
            app.MaxzonesizealongxEditField_2Label.Text = 'Max. zone size along x ';
            app.MaxzonesizealongxEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.MaxzonesizealongxEditField.Value = 30;
            app.alongyEditField_6Label = uilabel(app.WorkingzonesinmetresPanel);
            app.alongyEditField_6Label.Text = 'along y ';
            app.MaxzonesizealongyEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.MaxzonesizealongyEditField.Value = 60;
            app.IncrementofzonedimensionalongxLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.IncrementofzonedimensionalongxLabel.Text = 'Increment of zone dimension along x * ';
            app.IncrementofzonedimensionalongxEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.IncrementofzonedimensionalongxEditField.Tooltip = {'Please make sure (Z_max - x_min) / x_increment = integer'};
            app.alongyLabel = uilabel(app.WorkingzonesinmetresPanel);
            app.alongyLabel.Text = 'along y * ';
            app.IncrementofzonedimensionalongyEditField = uieditfield(app.WorkingzonesinmetresPanel, 'numeric');
            app.OptimisationoptionsButtonGroup = uibuttongroup(app.TabPref);
            app.OptimisationoptionsButtonGroup.Title = 'Optimisation options';
            app.IfaposterioriButton = uiradiobutton(app.OptimisationoptionsButtonGroup);
            app.IfaposterioriButton.Tooltip = {'A posteriori: find a series of solutions, and plot Pareto front. Checks extreme cases and calculates the factors, if no factors supplied.'; ''; 'Recommended if run without normalisation.'};
            app.IfaposterioriButton.Text = 'If a posteriori *:';
            app.IfaprioriButton = uiradiobutton(app.OptimisationoptionsButtonGroup);
            app.IfaprioriButton.Tooltip = {'A priori: find one solution with assigned weighting, using weighted sum.'};
            app.IfaprioriButton.Text = 'If a priori *:';
            app.IfaprioriButton.Value = true;
            app.Factorforobj1Label = uilabel(app.OptimisationoptionsButtonGroup);
            app.Factorforobj1Label.Text = 'Factor for obj 1 *';
            app.Factorforobj1EditField = uieditfield(app.OptimisationoptionsButtonGroup, 'numeric');
            app.Factorforobj1EditField.Limits = [0 100];
            app.Factorforobj1EditField.Tooltip = {'For normalisation:'; ''; 'Obj = factor 1 * obj 1 + factor 2 * obj 2'};
            app.Obj1totaldirectcostObj2totaldurationLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.Obj1totaldirectcostObj2totaldurationLabel.Text = 'Obj 1: total direct cost;    Obj 2: total duration';
            app.Factorforobj2Label = uilabel(app.OptimisationoptionsButtonGroup);
            app.Factorforobj2Label.Text = 'Factor for obj 2 *';
            app.Factorforobj2EditField = uieditfield(app.OptimisationoptionsButtonGroup, 'numeric');
            app.Factorforobj2EditField.Limits = [0 100];
            app.Factorforobj2EditField.Tooltip = {'For normalisation:'; ''; 'Obj = factor 1 * obj 1 + factor 2 * obj 2'};
            app.Weightofobj1w1EditFieldLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.Weightofobj1w1EditFieldLabel.Text = 'Weight of obj 1 (w1)';
            app.Weightofobj1w1EditField = uieditfield(app.OptimisationoptionsButtonGroup, 'numeric');
            app.Weightofobj1w1EditField.Value = 0.1;
            app.TimeunitoftheproblemDropDownLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.TimeunitoftheproblemDropDownLabel.Text = 'Time unit of the problem *';
            app.TimeunitoftheproblemDropDown = uidropdown(app.OptimisationoptionsButtonGroup);
            app.TimeunitoftheproblemDropDown.Items = {'2-hr', '4-hr', '8-hr', '16-hr'};
            app.TimeunitoftheproblemDropDown.ItemsData = {'2', '4', '8', '16'};
            app.TimeunitoftheproblemDropDown.Value = '8';
            app.Weightofobj2w21w1Label = uilabel(app.OptimisationoptionsButtonGroup);
            app.Weightofobj2w21w1Label.Text = 'Weight of obj 2 (w2 = 1 - w1) ';
            app.NumsetsEditFieldLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.NumsetsEditFieldLabel.Text = 'Num. sets *';
            app.NumsetsEditField = uieditfield(app.OptimisationoptionsButtonGroup, 'numeric');
            app.NumsetsEditField.Limits = [1 100];
            app.NumsetsEditField.Tooltip = {'How many points in the trade-off plot'};
            app.NumsetsEditField.Value = 1;
            app.MaxtimeperruninsEditField_2Label = uilabel(app.OptimisationoptionsButtonGroup);
            app.MaxtimeperruninsEditField_2Label.Text = 'Max. time per run * (in s)';
            app.MaxtimeperruninsEditField = uieditfield(app.OptimisationoptionsButtonGroup, 'numeric');
            app.MaxtimeperruninsEditField.Limits = [100 7200];
            app.MaxtimeperruninsEditField.Tooltip = {'Suggestions:'; ''; '900 or 1800 (15 to 30 min) for small problem (e.g. <3000 variables);'; ''; '3600 (1-hr) or higher for larger problem.'};
            app.MaxtimeperruninsEditField.Value = 600;
            app.Generateinitialsolutiononlywithw21CheckBox = uicheckbox(app.OptimisationoptionsButtonGroup);
            app.Generateinitialsolutiononlywithw21CheckBox.Text = 'Generate initial solution only, with w2 = 1';
            app.Generateinitialsolutiononlywithw21CheckBox.Value = true;
            app.whennvarsisverylargeornearoptimalsolutionisnotrequiredLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.whennvarsisverylargeornearoptimalsolutionisnotrequiredLabel.Text = {'(when nvars is very large , or'; 'near-optimal solution is not required)'};
            app.fornormalisationifknownotherwiseleaveboth0Label = uilabel(app.OptimisationoptionsButtonGroup);
            app.fornormalisationifknownotherwiseleaveboth0Label.Text = '(for normalisation, if known; otherwise leave both = 0)';
            app.onlywhenincrementofzonedimension0alongxandyLabel = uilabel(app.OptimisationoptionsButtonGroup);
            app.onlywhenincrementofzonedimension0alongxandyLabel.Text = '(only when increment of zone dimension = 0 along x and y)';
            app.ResourceavailabilityPanel = uipanel(app.TabPref);
            app.ResourceavailabilityPanel.Tooltip = {'Resource:'; 'A type of working team (e.g. a mountage team, an installation team, etc.).'; ''; 'Resource availability Qr:'; 'How many teams of team type r can be mobilised at the same time (e.g. if res 1 refers to mountage team, then max. 2 mountage teams on site simultaneously if Qr = 2).'};
            app.ResourceavailabilityPanel.Title = 'Resource availability *';
            app.NumresourcetypesEditField_3Label = uilabel(app.ResourceavailabilityPanel);
            app.NumresourcetypesEditField_3Label.Text = 'Num. resource types';
            app.NumresourcetypesEditField_3 = uieditfield(app.ResourceavailabilityPanel, 'text');
            app.NumresourcetypesEditField_3.Editable = 'off';
            app.NumresourcetypesEditField_3.Tooltip = {''};
            app.Numres1SpinnerLabel = uilabel(app.ResourceavailabilityPanel);
            app.Numres1SpinnerLabel.Text = 'Num. res 1';
            app.Numres1Spinner = uispinner(app.ResourceavailabilityPanel);
            app.Numres1Spinner.Limits = [1 3];
            app.Numres1Spinner.Value = 1;
            app.Numres2SpinnerLabel = uilabel(app.ResourceavailabilityPanel);
            app.Numres2SpinnerLabel.Text = 'Num. res 2';
            app.Numres2Spinner = uispinner(app.ResourceavailabilityPanel);
            app.Numres2Spinner.Limits = [1 3];
            app.Numres2Spinner.Value = 1;
            app.Numres3SpinnerLabel = uilabel(app.ResourceavailabilityPanel);
            app.Numres3SpinnerLabel.Text = 'Num. res 3';
            app.Numres3Spinner = uispinner(app.ResourceavailabilityPanel);
            app.Numres3Spinner.Limits = [1 3];
            app.Numres3Spinner.Value = 1;
            app.TabOpt = uitab(app.TabGroup);
            app.TabOpt.Title = '4. Check & run';
            app.CheckproblemsettingsandrunLabel = uilabel(app.TabOpt);
            app.CheckproblemsettingsandrunLabel.Text = 'Check problem settings and run';
            app.EstimateoptimisationparametersButton = uibutton(app.TabOpt, 'push');
            app.EstimateoptimisationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateoptimisationparametersButtonPushed, true);
            app.EstimateoptimisationparametersButton.Text = 'Estimate optimisation parameters';
            app.NumvariablesnvarsSuggestedtobe6000EditFieldLabel = uilabel(app.TabOpt);
            app.NumvariablesnvarsSuggestedtobe6000EditFieldLabel.Text = {'Num. variables (nvars) *'; '(Suggested to be < 6,000)'};
            app.NumvariablesnvarsSuggestedtobe6000EditField = uieditfield(app.TabOpt, 'text');
            app.NumvariablesnvarsSuggestedtobe6000EditField.Editable = 'off';
            app.NumvariablesnvarsSuggestedtobe6000EditField.Tooltip = {'Suggested to be < 6,000.'; ''; 'Options to reduce nvars:'; ''; 'Use larger time unit'; 'Set larger min. zone dimensions'};
            app.MaxtimecompletingallrunsinminLabel = uilabel(app.TabOpt);
            app.MaxtimecompletingallrunsinminLabel.Text = 'Max. time completing all runs (in min) *';
            app.MaxtimecompletingallrunsinminEditField = uieditfield(app.TabOpt, 'text');
            app.MaxtimecompletingallrunsinminEditField.Editable = 'off';
            app.MaxtimecompletingallrunsinminEditField.Tooltip = {'Options to reduce the time:'; ''; 'Reduce nvars'; 'Reduce num. runs in a posteriori'};
            app.TabGroup6 = uitabgroup(app.TabOpt);
            app.ActivityclusterssampleTab = uitab(app.TabGroup6);
            app.ActivityclusterssampleTab.Title = 'Activity clusters (sample)';
            app.TabGroup7 = uitabgroup(app.ActivityclusterssampleTab);
            app.structuralactivityclustersTab = uitab(app.TabGroup7);
            app.structuralactivityclustersTab.Title = 'structural activity clusters';
            app.UITable_tableZoned_struct = uitable(app.structuralactivityclustersTab);
            app.UITable_tableZoned_struct.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_tableZoned_struct.RowName = {};
            app.MEPactivityclustersTab = uitab(app.TabGroup7);
            app.MEPactivityclustersTab.Title = 'MEP activity clusters';
            app.UITable_tableZoned_mep = uitable(app.MEPactivityclustersTab);
            app.UITable_tableZoned_mep.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_tableZoned_mep.RowName = {};
            app.MaxnumzonesalongxEditField_2Label = uilabel(app.TabOpt);
            app.MaxnumzonesalongxEditField_2Label.Text = 'Max. num zones along x ';
            app.MaxnumzonesalongxEditField = uieditfield(app.TabOpt, 'numeric');
            app.alongyEditField_6Label_2 = uilabel(app.TabOpt);
            app.alongyEditField_6Label_2.Text = 'along y ';
            app.MaxnumzonesalongyEditField = uieditfield(app.TabOpt, 'numeric');
            app.NumfloorsEditField_3Label = uilabel(app.TabOpt);
            app.NumfloorsEditField_3Label.Text = 'Num. floors';
            app.NumfloorsEditField_2 = uieditfield(app.TabOpt, 'numeric');
            app.NumactivitiesEditField_2Label = uilabel(app.TabOpt);
            app.NumactivitiesEditField_2Label.Text = 'Num. activities';
            app.NumactivitiesEditField = uieditfield(app.TabOpt, 'numeric');
            app.NumactmodesEditField_2Label = uilabel(app.TabOpt);
            app.NumactmodesEditField_2Label.Text = 'Num. act. modes';
            app.NumactmodesEditField = uieditfield(app.TabOpt, 'numeric');
            app.NumresourcetypesEditField_4Label = uilabel(app.TabOpt);
            app.NumresourcetypesEditField_4Label.Text = 'Num. resource types';
            app.NumresourcetypesEditField_2 = uieditfield(app.TabOpt, 'numeric');
            app.NumdaysestEditField_2Label = uilabel(app.TabOpt);
            app.NumdaysestEditField_2Label.Text = 'Num. days (est.)';
            app.NumdaysestEditField = uieditfield(app.TabOpt, 'numeric');
            app.NumtimeunitsestEditField_2Label = uilabel(app.TabOpt);
            app.NumtimeunitsestEditField_2Label.Text = 'Num. time units (est.)';
            app.NumtimeunitsestEditField = uieditfield(app.TabOpt, 'numeric');
            app.NumrunsofoptimisationEditField_2Label = uilabel(app.TabOpt);
            app.NumrunsofoptimisationEditField_2Label.Text = 'Num. runs of optimisation';
            app.NumrunsofoptimisationEditField = uieditfield(app.TabOpt, 'numeric');
            app.TabResult = uitab(app.TabGroup);
            app.TabResult.Title = '5. Result';
            app.RunButton = uibutton(app.TabResult, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunoptimisationshowandsaveresultsLabel = uilabel(app.TabResult);
            app.RunoptimisationshowandsaveresultsLabel.Text = 'Run optimisation, show and save results';
            app.ExportasXlsxFileBtn = uibutton(app.TabResult, 'push');
            app.ExportasXlsxFileBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportasXlsxFileBtnButtonPushed, true);
            app.ExportasXlsxFileBtn.Text = 'Export as .xlsx file';
            app.ExportthescheduleforapriorimethodLabel = uilabel(app.TabResult);
            app.ExportthescheduleforapriorimethodLabel.Text = 'Export the schedule (for a priori method)';
            app.DirectoryEditField_5Label = uilabel(app.TabResult);
            app.DirectoryEditField_5Label.Text = 'Directory';
            app.ExportScheduleDirectoryEditField = uieditfield(app.TabResult, 'text');
            app.ExportScheduleDirectoryEditField.Value = 'E:\THESIS\software\Case\schedule result_case_1.xlsx';
            app.ProjectstartingdateDatePickerLabel = uilabel(app.TabResult);
            app.ProjectstartingdateDatePickerLabel.Text = 'Project starting date';
            app.ProjectstartingdateDatePicker = uidatepicker(app.TabResult);
            app.ProjectstartingdateDatePicker.Value = datetime([2022 5 4]);
            app.TabGroup8 = uitabgroup(app.TabResult);
            app.ResultaposterioriTab = uitab(app.TabGroup8);
            app.ResultaposterioriTab.Title = 'Result - a posteriori';
            app.UIAxes = uiaxes(app.ResultaposterioriTab);
            title(app.UIAxes, 'Time-cost trade-off')
            xlabel(app.UIAxes, 'Total duration')
            ylabel(app.UIAxes, 'Total direct cost')
            zlabel(app.UIAxes, 'Z')
            app.UITable_resultIfAPosteriori = uitable(app.ResultaposterioriTab);
            app.UITable_resultIfAPosteriori.ColumnName = {'(Variable: different coeff; Row: total cost & duration)'};
            app.UITable_resultIfAPosteriori.RowName = {'Total direct cost'; 'Total duration'};
            app.ResultaprioriTab = uitab(app.TabGroup8);
            app.ResultaprioriTab.Title = 'Result - a priori';
            app.UITable_resultIfAPriori = uitable(app.ResultaprioriTab);
            app.UITable_resultIfAPriori.ColumnName = {'(Schedule)'};
            app.UITable_resultIfAPriori.RowName = {'Total direct cost'; 'Total duration'};
            app.takestimebeforetheresultisshownLabel = uilabel(app.TabResult);
            app.takestimebeforetheresultisshownLabel.Text = '* takes time before the result is shown';
            app.ContextMenu = uicontextmenu(app.Scheduler);
            app.TochangeunitfrommtommMenu_2 = uimenu(app.ContextMenu);
            app.TochangeunitfrommtommMenu_2.Text = 'To change unit from m to mm';
            app.TochangeunitfrommtommMenu = uimenu(app.TochangeunitfrommtommMenu_2);
            app.TochangeunitfrommtommMenu.Text = 'To change unit from m to mm';
            app.TokeepdiscardcompoMenu = uimenu(app.ContextMenu);
            app.TokeepdiscardcompoMenu.Text = 'To keep/discard compo';
            % Show the figure after all components are created
            app.Scheduler.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test_1210

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Scheduler)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Scheduler)
        end
    end
end